classdef COVID19_Colombia_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        BoletndeinformacinCOVID19COLOMBIALabel  matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        NacionalTab                     matlab.ui.container.Tab
        GenerardatosdelboletnButton     matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
        TabGroup2                       matlab.ui.container.TabGroup
        AtencinyEstadoTab               matlab.ui.container.Tab
        TabGroup3                       matlab.ui.container.TabGroup
        ConfirmadosTab                  matlab.ui.container.Tab
        axesAtencionConfirmados         matlab.ui.control.UIAxes
        FallecidosEditFieldLabel        matlab.ui.control.Label
        FallecidosEditField             matlab.ui.control.NumericEditField
        RecuperadosEditFieldLabel       matlab.ui.control.Label
        RecuperadosEditField            matlab.ui.control.NumericEditField
        ActivosEditFieldLabel           matlab.ui.control.Label
        ActivosEditField                matlab.ui.control.NumericEditField
        ActivosTab                      matlab.ui.container.Tab
        EnCasaEditFieldLabel            matlab.ui.control.Label
        EnCasaEditField                 matlab.ui.control.NumericEditField
        HospitalEditFieldLabel          matlab.ui.control.Label
        HospitalEditField               matlab.ui.control.NumericEditField
        HospitalUCIEditFieldLabel       matlab.ui.control.Label
        HospitalUCIEditField            matlab.ui.control.NumericEditField
        axesAtencionActivos             matlab.ui.control.UIAxes
        axesEstadoPaciente              matlab.ui.control.UIAxes
        LeveEditFieldLabel              matlab.ui.control.Label
        LeveEditField                   matlab.ui.control.NumericEditField
        ModeradoEditFieldLabel          matlab.ui.control.Label
        ModeradoEditField               matlab.ui.control.NumericEditField
        GraveEditFieldLabel             matlab.ui.control.Label
        GraveEditField                  matlab.ui.control.NumericEditField
        TipoyProcedenciaTab             matlab.ui.container.Tab
        axesTipo                        matlab.ui.control.UIAxes
        RelacionadoEditFieldLabel       matlab.ui.control.Label
        RelacionadoEditField            matlab.ui.control.NumericEditField
        EnEstudioEditFieldLabel         matlab.ui.control.Label
        EnEstudioEditField              matlab.ui.control.NumericEditField
        ImportadoEditFieldLabel         matlab.ui.control.Label
        ImportadoEditField              matlab.ui.control.NumericEditField
        axesPaises                      matlab.ui.control.UIAxes
        EdadesyGneroTab                 matlab.ui.container.Tab
        axesGenero                      matlab.ui.control.UIAxes
        HombresEditFieldLabel           matlab.ui.control.Label
        HombresEditField                matlab.ui.control.NumericEditField
        MujeresEditFieldLabel           matlab.ui.control.Label
        MujeresEditField                matlab.ui.control.NumericEditField
        Menoresa35aosEditFieldLabel     matlab.ui.control.Label
        Menoresa35aosEditField          matlab.ui.control.NumericEditField
        Mayoresa35aosEditFieldLabel     matlab.ui.control.Label
        Mayoresa35aosEditField          matlab.ui.control.NumericEditField
        axesRangoEdades                 matlab.ui.control.UIAxes
        MapadeCasosTab                  matlab.ui.container.Tab
        PanelMapa1                      matlab.ui.container.Panel
        PanelMapa2                      matlab.ui.container.Panel
        ModelosdePrediccinTab           matlab.ui.container.Tab
        TabGroup3_2                     matlab.ui.container.TabGroup
        Exponencial1Tab                 matlab.ui.container.Tab
        axesModeloExponencial           matlab.ui.control.UIAxes
        aEditFieldLabel                 matlab.ui.control.Label
        valorde_a                       matlab.ui.control.NumericEditField
        bEditFieldLabel                 matlab.ui.control.Label
        valorde_b                       matlab.ui.control.NumericEditField
        fxaexpbxLabel                   matlab.ui.control.Label
        R2EditField_2Label              matlab.ui.control.Label
        R2EditField_2                   matlab.ui.control.NumericEditField
        RMSEEditFieldLabel              matlab.ui.control.Label
        RMSEEditField                   matlab.ui.control.NumericEditField
        ProyeccindecasosLabel           matlab.ui.control.Label
        UITable2                        matlab.ui.control.Table
        Exponencial2Tab                 matlab.ui.container.Tab
        axesModeloExponencial1          matlab.ui.control.UIAxes
        fxaxbLabel                      matlab.ui.control.Label
        RMSEEditFieldexponen1           matlab.ui.control.Label
        RMSEEditField_2                 matlab.ui.control.NumericEditField
        ProyeccindecasosLabel_3         matlab.ui.control.Label
        UITableexponen1                 matlab.ui.control.Table
        aEditFieldLabel_2               matlab.ui.control.Label
        valorde_a1                      matlab.ui.control.NumericEditField
        bEditFieldLabel_2               matlab.ui.control.Label
        valorde_b1                      matlab.ui.control.NumericEditField
        R2EditField_3Label              matlab.ui.control.Label
        R2EditFieldexponen1             matlab.ui.control.NumericEditField
        CuadrticoTab                    matlab.ui.container.Tab
        axesModeloCuadratico            matlab.ui.control.UIAxes
        fxax2bxcLabel                   matlab.ui.control.Label
        aLabel                          matlab.ui.control.Label
        valorde_a_2                     matlab.ui.control.NumericEditField
        bLabel                          matlab.ui.control.Label
        valorde_b_2                     matlab.ui.control.NumericEditField
        cLabel                          matlab.ui.control.Label
        valorde_c_2                     matlab.ui.control.NumericEditField
        ProyeccindecasosLabel_2         matlab.ui.control.Label
        UITable2_2                      matlab.ui.control.Table
        RMSEEditFieldLabel_2            matlab.ui.control.Label
        RMSEEditField_3                 matlab.ui.control.NumericEditField
        R2EditField_3Label_2            matlab.ui.control.Label
        R2EditField_3                   matlab.ui.control.NumericEditField
        LinealTab                       matlab.ui.container.Tab
        axesModeloExponencial_3         matlab.ui.control.UIAxes
        fxaxbLabel_2                    matlab.ui.control.Label
        ProyeccindecasosLabel_4         matlab.ui.control.Label
        UITableexponen1_2               matlab.ui.control.Table
        RMSEEditFieldLabel_3            matlab.ui.control.Label
        RMSEEditField_4                 matlab.ui.control.NumericEditField
        aEditFieldLabel_3               matlab.ui.control.Label
        valorde_a_3                     matlab.ui.control.NumericEditField
        bEditFieldLabel_3               matlab.ui.control.Label
        valorde_b_3                     matlab.ui.control.NumericEditField
        R2EditField_4Label              matlab.ui.control.Label
        R2EditField_4                   matlab.ui.control.NumericEditField
        ParaobservarqueelmodeloCOVID19noeslinealLabel  matlab.ui.control.Label
        SegmentacinLabel                matlab.ui.control.Label
        TotalCasosConfirmadosEditFieldLabel  matlab.ui.control.Label
        TotalCasosConfirmadosEditField  matlab.ui.control.NumericEditField
        Label_2                         matlab.ui.control.Label
        CargandoPlot                    matlab.ui.control.EditField
        PrimerCasoConfirmadoEditFieldLabel  matlab.ui.control.Label
        PrimerCasoConfirmadoEditField   matlab.ui.control.EditField
        TasadeletalidadEditField_2Label  matlab.ui.control.Label
        TasadeletalidadTotalidadEditField  matlab.ui.control.EditField
        DepartamentalTab                matlab.ui.container.Tab
        axesDepartamentos               matlab.ui.control.UIAxes
        ConfirmadosEditFieldLabel       matlab.ui.control.Label
        ConfirmadosDeparEditField       matlab.ui.control.NumericEditField
        RecuperadosEditField_2Label     matlab.ui.control.Label
        RecuperadosDeparEditField       matlab.ui.control.NumericEditField
        FallecidosEditField_2Label      matlab.ui.control.Label
        FallecidosDeparEditField        matlab.ui.control.NumericEditField
        ListaDepartamentos              matlab.ui.control.ListBox
        DepartamentosEditFieldLabel     matlab.ui.control.Label
        DepartamentosEditField          matlab.ui.control.NumericEditField
        axesComparDepartamento          matlab.ui.control.UIAxes
        Label                           matlab.ui.control.Label
        DeparEditField                  matlab.ui.control.EditField
        TasadeletalidadEditFieldLabel   matlab.ui.control.Label
        TasadeletalidadDeparEditField   matlab.ui.control.EditField
        ActivosEditField_2Label         matlab.ui.control.Label
        ActivosDeparEditField           matlab.ui.control.NumericEditField
        TabGroup4                       matlab.ui.container.TabGroup
        AtencinyEstadoTab_2             matlab.ui.container.Tab
        Label_6                         matlab.ui.control.Label
        EnCasaDeparEditField            matlab.ui.control.NumericEditField
        Label_7                         matlab.ui.control.Label
        HospitalDeparEditField          matlab.ui.control.NumericEditField
        Label_8                         matlab.ui.control.Label
        HospitalUCIDeparEditField       matlab.ui.control.NumericEditField
        axesEstadoPacienteDepar         matlab.ui.control.UIAxes
        Label_3                         matlab.ui.control.Label
        LeveDeparEditField              matlab.ui.control.NumericEditField
        Label_4                         matlab.ui.control.Label
        ModeradoDeparEditField          matlab.ui.control.NumericEditField
        Label_5                         matlab.ui.control.Label
        GraveDeparEditField             matlab.ui.control.NumericEditField
        axesCasosDepa                   matlab.ui.control.UIAxes
        axesAtencionPacienteDepar       matlab.ui.control.UIAxes
        EdadesyGneroTab_2               matlab.ui.container.Tab
        axesEdadesDepartamentos         matlab.ui.control.UIAxes
        axesPieDepartamentos            matlab.ui.control.UIAxes
        MujeresEditField_2Label         matlab.ui.control.Label
        MujeresDeparEditField           matlab.ui.control.NumericEditField
        HombresEditField_2Label         matlab.ui.control.Label
        HombresDeparEditField           matlab.ui.control.NumericEditField
        GenerarlacurvaButton            matlab.ui.control.Button
        ElprocesopuedetardarunpocoLabel  matlab.ui.control.Label
        CiudadMunicipioTab              matlab.ui.container.Tab
        ListaCiudades                   matlab.ui.control.ListBox
        MunicipiosEditFieldLabel        matlab.ui.control.Label
        MunicipiosEditField             matlab.ui.control.NumericEditField
        UITable                         matlab.ui.control.Table
        PanelMapaDepa                   matlab.ui.container.Panel
        PanelArreglo                    matlab.ui.container.Panel
        MapadeContagiosLabel            matlab.ui.control.Label
        ConfirmadosEditFieldLabel_2     matlab.ui.control.Label
        ConfirmadosDeparEditField_2     matlab.ui.control.NumericEditField
        RecuperadosEditField_2Label_2   matlab.ui.control.Label
        RecuperadosDeparEditField_2     matlab.ui.control.NumericEditField
        FallecidosEditField_2Label_2    matlab.ui.control.Label
        FallecidosDeparEditField_2      matlab.ui.control.NumericEditField
        TabGroup5                       matlab.ui.container.TabGroup
        AtencinTab                      matlab.ui.container.Tab
        axesAtencionPacienteDepar_2     matlab.ui.control.UIAxes
        EnCasaEditField_2Label          matlab.ui.control.Label
        EnCasaEditField_2               matlab.ui.control.NumericEditField
        HospitalEditField_2Label        matlab.ui.control.Label
        HospitalEditField_2             matlab.ui.control.NumericEditField
        HospitalUCIEditField_2Label     matlab.ui.control.Label
        HospitalUCIEditField_2          matlab.ui.control.NumericEditField
        EstadoTab                       matlab.ui.container.Tab
        axesEstadoPacienteDepar_2       matlab.ui.control.UIAxes
        LeveEditField_2Label            matlab.ui.control.Label
        LeveEditField_2                 matlab.ui.control.NumericEditField
        ModeradoEditField_2Label        matlab.ui.control.Label
        ModeradoEditField_2             matlab.ui.control.NumericEditField
        GraveEditField_2Label           matlab.ui.control.Label
        GraveEditField_2                matlab.ui.control.NumericEditField
        EdadTab                         matlab.ui.container.Tab
        axesEstadoPacienteDepar_3       matlab.ui.control.UIAxes
        Menoresa35aosEditField_2Label   matlab.ui.control.Label
        Menoresa35aosEditField_2        matlab.ui.control.NumericEditField
        Mayoresa35aosEditField_2Label   matlab.ui.control.Label
        Mayoresa35aosEditField_2        matlab.ui.control.NumericEditField
        GneroTab                        matlab.ui.container.Tab
        axesEstadoPacienteDepar_4       matlab.ui.control.UIAxes
        HombresEditField_2Label_2       matlab.ui.control.Label
        HombresDeparEditField_2         matlab.ui.control.NumericEditField
        MujeresEditField_2Label_2       matlab.ui.control.Label
        MujeresDeparEditField_2         matlab.ui.control.NumericEditField
        ComparacinTab                   matlab.ui.container.Tab
        axesEstadoPacienteDepar_5       matlab.ui.control.UIAxes
        Label_9                         matlab.ui.control.Label
        DeparEditField_2                matlab.ui.control.EditField
        TasadeletalidadEditFieldLabel_2  matlab.ui.control.Label
        TasadeletalidadDeparEditField_2  matlab.ui.control.EditField
        SobreNosotrosTab                matlab.ui.container.Tab
        CristianFRubioAguiarLabel       matlab.ui.control.Label
        IngJoseAFernndezGallegoLabel    matlab.ui.control.Label
        UniversidaddeIbaguLabel         matlab.ui.control.Label
        FacultaddeIngenieraElectrnicaLabel  matlab.ui.control.Label
        EstudiantedeingenieraelectrnicadesarrolladorLabel  matlab.ui.control.Label
        DirectorycoordinadorasociadoaldesarrollodelproyectoLabel  matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        Image4                          matlab.ui.control.Image
        Panel_2                         matlab.ui.container.Panel
        Image3                          matlab.ui.control.Image
        httpsgithubcomcristianrubioaButton  matlab.ui.control.Button
        GitHubLabel                     matlab.ui.control.Label
        LinkedinLabel                   matlab.ui.control.Label
        httpswwwlinkedincomincristianrubioaguiarButton  matlab.ui.control.Button
        ResearchgateLabel               matlab.ui.control.Label
        httpswwwresearchgatenetprofileJose_FernandezGallegoButton  matlab.ui.control.Button
        EMailLabel                      matlab.ui.control.Label
        httpcristianrubioagmailcomButton  matlab.ui.control.Button
        EMailLabel_2                    matlab.ui.control.Label
        httpjosefernandezunibagueeducoButton  matlab.ui.control.Button
        SistemadesarrolladoenunespaciodelaasignaturadeIAreferenteLabel  matlab.ui.control.Label
        SistemadesarrolladoenunespaciodelaasignaturadeIAreferenteLabel_2  matlab.ui.control.Label
        Image5                          matlab.ui.control.Image
        FechaReporteEditFieldLabel      matlab.ui.control.Label
        FechaReporteEditField           matlab.ui.control.EditField
    end

    methods (Access = public)

        
        function plot_casos_colombia(app,reporte, otros_datos)
            %app.CargandoPlot.Value = "Tardará unos segundos más ...";
            fechas_arreglo = unique(reporte);
            reales = [reporte, otros_datos];

        
            fechas_arreglo_time = datetime(fechas_arreglo);
            dt = caldiff(fechas_arreglo_time,'days');
            dt = string(dt); dt2 = dt.split("d"); dt2 = dt2(:,1); dt2 = str2double(dt2);
            
            cont = 0;
            for i = 1:length(dt2)
                if (dt2(i) > 1)
                    for j = 1:1:(dt2(i))
                        cont = cont + 1;
                        fechas_arreglo_fix(cont,1) = datetime(fechas_arreglo(i))+(j-1);
                    end
                elseif (dt2(i) == 1)
                    cont = cont + 1;
                    fechas_arreglo_fix(cont,1) = datetime(fechas_arreglo(i));
                end
                   
            end
            fechas_arreglo_fix(cont+1,1) = fechas_arreglo(end,1);  % warning (!)
            
            
            %% Confirmados 
            [C1,casos_confirmados] = unique(reporte, 'last', 'legacy');

            fix_confirmados = datetime(C1)+1;
            fix_confirmados = fix_confirmados-1; fix_confirmados = string(fix_confirmados);
            
           
            confirmados_arreglo_fix = zeros(length(fechas_arreglo_fix),1);
            for i = 1:length(C1)
                for j = 1:length(fechas_arreglo_fix)
                    if (fix_confirmados(i,1) == fechas_arreglo_fix(j,1))
                        confirmados_arreglo_fix(j,1) = casos_confirmados(i,1);
                        break
                    end
                   
                end
            end
            
            for i = 1:length(confirmados_arreglo_fix)
                if (confirmados_arreglo_fix(i,1) == 0 && i > 1)
                    confirmados_arreglo_fix(i,1) = confirmados_arreglo_fix(i-1,1);
                end
            end
        
            
            %% Recuperados 
            recu = reales((reales(:,2)=="Recuperado"));
            [C2,casos_recuperados] = unique(recu, 'last', 'legacy');

            fix_recuperados = datetime(C2)+1;
            fix_recuperados = fix_recuperados-1; fix_recuperados = string(fix_recuperados);
            
           
            recuperados_arreglo_fix = zeros(length(fechas_arreglo_fix),1);
            for i = 1:length(C2)
                for j = 1:length(fechas_arreglo_fix)
                    if (fix_recuperados(i,1) == fechas_arreglo_fix(j,1))
                        recuperados_arreglo_fix(j,1) = casos_recuperados(i,1);
                        break
                    end
                   
                end
            end
            
            for i = 1:length(recuperados_arreglo_fix)
               
                if (recuperados_arreglo_fix(i,1) == 0 && i > 1)
                    recuperados_arreglo_fix(i,1) = recuperados_arreglo_fix(i-1,1);
                end
            end
            
            %% Fallecidos 
            falle = reales((reales(:,2)=="Fallecido"));
            [C3,casos_fallecidos] = unique(falle, 'last', 'legacy');

            fix_fallecidos = datetime(C3)+1;
            fix_fallecidos = fix_fallecidos-1; fix_fallecidos = string(fix_fallecidos);
            
           
            fallecidos_arreglo_fix = zeros(length(fechas_arreglo_fix),1);
            for i = 1:length(C3)
                for j = 1:length(fechas_arreglo_fix)
                    if (fix_fallecidos(i,1) == fechas_arreglo_fix(j,1))
                        fallecidos_arreglo_fix(j,1) = casos_fallecidos(i,1);
                        break
                    end
                   
                end
            end
            
            for i = 1:length(fallecidos_arreglo_fix)            
                if (fallecidos_arreglo_fix(i,1) == 0 && i > 1)
                    fallecidos_arreglo_fix(i,1) = fallecidos_arreglo_fix(i-1,1);
                end
            end
            Plot_Casos(app, confirmados_arreglo_fix, recuperados_arreglo_fix, fallecidos_arreglo_fix);
        end
        
        
        function y = Plot_Casos(app, confirmados_arreglo_fix, recuperados_arreglo_fix, fallecidos_arreglo_fix) 
            
            dates_ejex = 1:length(confirmados_arreglo_fix); 
                                          
            plot(app.UIAxes,dates_ejex, confirmados_arreglo_fix, 'b*');
            legend(app.UIAxes,'Confirmados','Location','northwest')
                                    
            % Activos
            activos_arreglo_fix = confirmados_arreglo_fix - fallecidos_arreglo_fix - recuperados_arreglo_fix;
            
            plot(app.UIAxes_2, dates_ejex, recuperados_arreglo_fix, 'g*',dates_ejex, fallecidos_arreglo_fix,'r*');
            legend(app.UIAxes_2,{'Recuperados', 'Fallecidos'},'Location','northwest')
            
            plot(app.UIAxes_3, dates_ejex, confirmados_arreglo_fix, 'b*', dates_ejex, activos_arreglo_fix, 'c*');
            legend(app.UIAxes_3,{'Confirmados', 'Activos'},'Location','northwest')
            
            % Latitud y longitud Ciudades
            app.CargandoPlot.Value = "Datos cargados correctamente";
            model(app,confirmados_arreglo_fix);
            
        end
        
        function y = model(app,confirmados_arreglo_fix)
            
            dates_ejex = 1:length(confirmados_arreglo_fix);
            x = (dates_ejex)';
            y = confirmados_arreglo_fix;
            num1 = 1:90;
            
            % Exponencial2
            [f1, e1] = fit(x,y,'exp1');
            c1 = feval(f1,x);
            plot(app.axesModeloExponencial, x,y,'b*',x,c1,'-');
            app.valorde_a.Value = f1.a;
            app.valorde_b.Value = f1.b;
            app.R2EditField_2.Value = e1.rsquare;
            app.RMSEEditField.Value = e1.rmse;
            
            for c = 1:90
                proyec1(c) = (f1.a)*exp((f1.b)*(x(end)+c));
            end
              
            real1 = round(proyec1);
            tdatas1 = table(num1.',real1.','VariableNames',{'Número','Ciudad'})
            app.UITable2.Data = tdatas1;
            
            %app.daEditField.Value = confirmados_arreglo_fix(end) + proyec;
            
            % Cuadrática
            [f2, e2]= fit(x,y,'poly2');
            c2 = feval(f2,x);
            plot(app.axesModeloCuadratico, x,y,'b*',x,c2,'-');
            app.valorde_a_2.Value = f2.p1;
            app.valorde_b_2.Value = f2.p2;
            app.valorde_c_2.Value = f2.p3;
            app.R2EditField_3.Value = e2.rsquare;
            app.RMSEEditField_3.Value = e2.rmse;
            
            for c = 1:90
                proyec2(c) = f2.p1*(x(end)+c)^2 + f2.p2*(x(end)+c) + f2.p3;
            end
            
            real2 = round(proyec2);
            tdatas2 = table(num1.',real2.','VariableNames',{'Número','Ciudad'})
            app.UITable2_2.Data = tdatas2;
            
            % Exponencial1
            [f3, e3] = fit(x,y,'power1');
            c3 = feval(f3,x);
            plot(app.axesModeloExponencial1, x,y,'b*',x,c3,'-');
            app.valorde_a1.Value = f3.a;
            app.valorde_b1.Value = f3.b;
            app.R2EditFieldexponen1.Value = e3.rsquare;
            app.RMSEEditField_2.Value = e3.rmse;
            
            for c = 1:90
                proyec3(c) = (f3.a)*(x(end)+c)^(f3.b);
            end

            real3 = round(proyec3);
            tdatas3 = table(num1.',real3.','VariableNames',{'Número','Ciudad'})
            app.UITableexponen1.Data = tdatas3;
            
            
            % Lineal
            [f4, e4]= fit(x,y,'poly1');
            c4 = feval(f4,x);
            plot(app.axesModeloExponencial_3, x,y,'b*',x,c4,'-');
            app.valorde_a_3.Value = f4.p1;
            app.valorde_b_3.Value = f2.p2;
            app.R2EditField_4.Value = e4.rsquare;
            app.RMSEEditField_4.Value = e4.rmse;
            
            for c = 1:90
                proyec4(c) = f4.p1*(x(end)+c)^2 + f4.p2*(x(end)+c);
            end
            
            real4 = round(proyec4);
            tdatas4 = table(num1.',real4.','VariableNames',{'Número','Ciudad'})
            app.UITableexponen1_2.Data = tdatas4;
            
            
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            datos = readtable("Casos_positivos_de_COVID-19_en_Colombia.csv");

            C_afectados = datos(:,"CiudadDeUbicaci_n");
            C_afectados = table2array(C_afectados);
            C_afectados = string(C_afectados);
            
            total_ciuda = length(unique(C_afectados));
            app.MunicipiosEditField.Value = total_ciuda;
            
            ciudades = unique(C_afectados);
            
            %% Lo que sigue de Ciudad
            for c = 1:length(ciudades)
                valor_ciudad(c) = sum(count(C_afectados,ciudades(c)));
            end
            
            num = 1:length(ciudades);
            
            tdatas = table(num.',ciudades,valor_ciudad.','VariableNames',{'Número','Ciudad','Confirmados'})
            app.UITable.Data = tdatas;
            
            % Mapa de contagios
               
            %% Total departamentos
            D_afectados = datos(:,"DepartamentoODistrito");
            D_afectados = table2array(D_afectados);
            D_afectados = string(D_afectados);
            D_afectados(D_afectados == "Archipiélago de San Andrés Providencia y Santa Catalina") = "San Andrés";
            
            total_depa = length(unique(D_afectados));

            app.DepartamentosEditField.Value = total_depa;
            departamento = unique(D_afectados);
            departamento = departamento(departamento~="-   -");
            geobubble(app.PanelArreglo, 4.57,-74.29);
                        
            tabla = [-1.2061, -71.2627
            6.2453, -75.6259
            10.9447, -74.7940
            10.9379, -74.8097
            4.6153, -74.0968
            8.6014, -74.2018
            5.4439, -73.3227
            3.8180, -76.9920
            5.3077, -75.4202
            0.7201, -74.1198
            10.4464, -75.5147
            5.6923, -71.0105
            2.4011, -76.8282
            10.1960, -73.3188
            5.4756, -77.0439
            5.2822, -74.4576
            8.6750, -75.8811
            2.8458, -75.3123
            11.2828, -72.5366
            10.1771, -74.2266
            3.1254, -73.0337
            1.6461, -77.9477
            8.1191, -73.0239
            4.5042, -75.6838
            4.7950, -75.6596
            12.5823, -81.7073
            11.2415, -74.2064
            7.0752, -73.2317
            8.8194, -74.7363
            4.3539, -75.2402
            3.3775, -76.5117];
        
            for c = 1:length(tabla)
                valor_depar(c) = sum(count(D_afectados,departamento(c)));
            end

            color_depar = cosd(5*valor_depar);
            vector_datos_mapa = [tabla(:,1) tabla(:,2)];  

            % Genera Mapa3
            gx3 = geoaxes(app.PanelMapaDepa);
            %geoscatter(gx,lat,lon,A,C,'^')
            %geoscatter(gx3, vector_datos_mapa(vector_pos,1),vector_datos_mapa(vector_pos,2),vector_datos_mapa(vector_pos,3),'filled');
            %geoscatter(gx3, vector_datos_mapa(:,1),vector_datos_mapa(:,2),valor_depar.',color_depar,'filled')
            geoscatter(gx3, vector_datos_mapa(:,1),vector_datos_mapa(:,2))
            geobasemap(gx3,'grayland') 
            
        end

        % Value changed function: TotalCasosConfirmadosEditField
        function TotalCasosConfirmadosEditFieldValueChanged(app, event)
            app.TotalCasosConfirmadosEditField.Value = value;
        end

        % Button pushed function: GenerardatosdelboletnButton
        function GenerardatosdelboletnButtonPushed(app, event)
            app.CargandoPlot.Value = "Cargando gráficas de datos acumulados (espera un poco más ... )";
            
            % Cargando datos
            datos = readtable("Casos_positivos_de_COVID-19_en_Colombia.csv");
            
            %%% Columna fechaReporteWeb %%%
            reporte = datos(:,"fechaReporteWeb");
            reporte = table2array(reporte);
            
            % Último reporte
            pos = size(reporte);
            pos = pos(1);
            fecha_reporte =  string(reporte(pos));
            ultimo_reporte = strsplit(fecha_reporte,'T');
            ultimo_reporte = ultimo_reporte(1);
            app.FechaReporteEditField.Value = ultimo_reporte;
            
            % Primer reporte
            primer_reporte = string(reporte(1));
            primer_reporte = strsplit(primer_reporte,'T');
            primer_reporte = primer_reporte(1);
            app.PrimerCasoConfirmadoEditField.Value = primer_reporte;
                        
            
            %%% Columna IDDeCaso %%%
            %confirmados = datos(:,"IDDeCaso");
            %confirmados1 = table2array(confirmados);
            
            % Confirmados
            %total_confirmados = length(confirmados1);
            %app.TotalCasosConfirmadosEditField.Value = total_confirmados;

                 
            % Recuperados
            reporte = string(reporte);
            reporte = split(reporte,[" ","T",":"]);
            reporte = reporte(:,1);
            % por que la cagaron
            reporte(reporte == "-") = "2020-04-24";
            %reporte = unique(reporte);

            %%% Columna atenci_n %%%
            otros_datos = datos(:,"atenci_n");
            otros_datos = table2array(otros_datos);
            otros_datos = string(otros_datos);
            
            
            % Recuperados
            recuperados = count(otros_datos,'Recuperado');
            recuperados = sum(recuperados);
            app.RecuperadosEditField.Value = recuperados;
            
            % Fallecidos
            fallecidos = count(otros_datos,'Fallecido');
            fallecidos = sum(fallecidos);
            app.FallecidosEditField.Value = fallecidos;
          
     
            %%% Columna Sexo %%%
            genero = datos(:,"Sexo");
            genero = table2array(genero);
            genero = string(genero);
            genero(genero == "m") = "M";
            genero(genero == "f") = "F";
                       
            % Hombres
            hombres = count(genero,'M');
            hombres = sum(hombres);
            app.HombresEditField.Value = hombres;
            
           % Mujeres
            mujeres = count(genero,'F');
            mujeres = sum(mujeres);
            app.MujeresEditField.Value = mujeres;
           
            
            total_confirmados = hombres + mujeres;
            app.TotalCasosConfirmadosEditField.Value = round(total_confirmados);

            % Confirmados
              
            % Activos
            activos = total_confirmados - recuperados - fallecidos;
            app.ActivosEditField.Value = activos(length(activos));

            vector_pie_confirmados = [activos, recuperados, fallecidos];
            
            pie(app.axesAtencionConfirmados,vector_pie_confirmados); 
            legend(app.axesAtencionConfirmados,{'Activos', 'Recuperados', 'Fallecidos'},'Location','west')
            
            
            % En Casa
            otros_datos(otros_datos == "casa") = "Casa";
            casa = count(otros_datos,'Casa');
            casa = sum(casa);
            app.EnCasaEditField.Value = casa;
            
            % En UCI
            uci = count(otros_datos,'Hospital UCI');
            uci = sum(uci);
            app.HospitalUCIEditField.Value = uci;
            
            % En Hospital
            %hospital = count(otros_datos,'Hospital');
            %hospital = sum(hospital);
            % arreglo 
            hospital = activos - casa - uci;
            app.HospitalEditField.Value = hospital;

            vector_pie_estancia = [casa, hospital, uci];
            pie(app.axesAtencionActivos,vector_pie_estancia);
            legend(app.axesAtencionActivos,{'En Casa', 'Hospital', 'Hospital UCI'},'Location','west')


            %porcen_hombres = (hombres * 100)/total_h_m;
            %porcen_mujeres = (mujeres * 100)/total_h_m;
            % Diagrama de pastel genero
            vector_pie_genero = [hombres, mujeres];
            explode_genero = [1 0];
            pie(app.axesGenero,vector_pie_genero, explode_genero);
            legend(app.axesGenero,{'Hombres', 'Mujeres'},'Location','northwest')
            
            % Distribución por estado de casos activos
            estado = datos(:,"Estado");
            estado = table2array(estado);
            estado = string(estado);
            
            estado(estado == "leve") = "Leve";
            
            moderado = count(estado,'Moderado');
            moderado = sum(moderado);
            app.ModeradoEditField.Value = moderado;
            
            grave = count(estado,'Grave');
            grave = sum(grave);
            app.GraveEditField.Value  = grave;
            
            %leve = count(estado,'Leve');
            %leve = sum(leve);
            % arreglo
            leve = total_confirmados-fallecidos-recuperados-moderado-grave;
            app.LeveEditField.Value = leve;
      
            vector_pie_estado = [leve, moderado, grave, recuperados, fallecidos];
            pie3(app.axesEstadoPaciente,vector_pie_estado);
            legend(app.axesEstadoPaciente,{'Leve', 'Moderado', 'Grave', 'Recuperado', 'Fallecido'},'Location','west')
           
            
            %% Mayores y menores
            edad = datos(:,"Edad");
            edad = table2array(edad);
            edad = string(edad);    
            edad = str2double(edad);
            menores_35 = edad(edad <= 35);
            menores_35 = length(menores_35);
            mayores_35 = find(edad > 35);
            mayores_35 = length(mayores_35);
            app.Menoresa35aosEditField.Value = menores_35;
            app.Mayoresa35aosEditField.Value = mayores_35;
            
            edades_genero = [edad,genero];

            edg_m = edades_genero((edades_genero(:,2)=="F"));
            edg_h = edades_genero((edades_genero(:,2)=="M"));
            
            edad_mujeres = str2double(edg_m(:,1));
            edad_hombres = str2double(edg_h(:,1));
            
            % Rango mujeres
            e_09m = length(edad_mujeres((edad_mujeres<10)));
            e_1019m = length(edad_mujeres((edad_mujeres>=10 & edad_mujeres<20)));
            e_2029m = length(edad_mujeres((edad_mujeres>=20 & edad_mujeres<30)));
            e_3039m = length(edad_mujeres((edad_mujeres>=30 & edad_mujeres<40)));
            e_4049m = length(edad_mujeres((edad_mujeres>=40 & edad_mujeres<50)));
            e_5059m = length(edad_mujeres((edad_mujeres>=50 & edad_mujeres<60)));
            e_6069m = length(edad_mujeres((edad_mujeres>=60 & edad_mujeres<70)));
            e_7079m = length(edad_mujeres((edad_mujeres>=70 & edad_mujeres<80)));
            e_8089m = length(edad_mujeres((edad_mujeres>=80 & edad_mujeres<90)));
            e_9099m = length(edad_mujeres((edad_mujeres>=90 & edad_mujeres<110)));
            
            
            % Rango hombres
            e_09h = length(edad_hombres((edad_hombres<10)));
            e_1019h = length(edad_hombres((edad_hombres>=10 & edad_hombres<20)));
            e_2029h = length(edad_hombres((edad_hombres>=20 & edad_hombres<30)));
            e_3039h = length(edad_hombres((edad_hombres>=30 & edad_hombres<40)));
            e_4049h = length(edad_hombres((edad_hombres>=40 & edad_hombres<50)));
            e_5059h = length(edad_hombres((edad_hombres>=50 & edad_hombres<60)));
            e_6069h = length(edad_hombres((edad_hombres>=60 & edad_hombres<70)));
            e_7079h = length(edad_hombres((edad_hombres>=70 & edad_hombres<80)));
            e_8089h = length(edad_hombres((edad_hombres>=80 & edad_hombres<90)));
            e_9099h = length(edad_hombres((edad_hombres>=90 & edad_hombres<110)));
            
            v_barh_edades = [-e_9099m e_9099h; -e_8089m e_8089h; -e_7079m e_7079h; -e_6069m e_6069h; -e_5059m e_5059h; -e_4049m e_4049h; -e_3039m e_3039h; -e_2029m e_2029h; -e_1019m e_1019h; -e_09m e_09h];
            %labels = categorical({'90-99', '80-89', '70-79', '60-69', '50-59', '40-49', '30-39', '20-29', '10-19', '0-9'});
            barh(app.axesRangoEdades,v_barh_edades, 'stacked', 'EdgeColor',[0 1 1],'LineWidth',1.5);
            yticklabels(app.axesRangoEdades,{'90-99', '80-89', '70-79', '60-69', '50-59', '40-49', '30-39', '20-29', '10-19', '0-9'})
            legend(app.axesRangoEdades,{'Hombres', 'Mujeres'},'Location','northwest')
            
            %%% Columna Tipo %%%
            tipo = datos(:,"Tipo");
            tipo = table2array(tipo);
            tipo = string(tipo);
            
            % Relacionado
            tipo(tipo == "relacionado") = "Relacionado";
            relacionado = count(tipo,'Relacionado');
            relacionado = sum(relacionado);
            app.RelacionadoEditField.Value = relacionado;
            
            % Importado
            importado = count(tipo,'Importado');
            importado = sum(importado);
            app.ImportadoEditField.Value = importado;
            
            % En estudio
            %tipo(tipo == "En estudio") = "En Estudio";
            %estudio = count(tipo,'En Estudio');
            %estudio = sum(estudio);
            estudio = total_confirmados - importado - relacionado;
            app.EnEstudioEditField.Value = estudio;
            
  
            vector_pie_tipo = [relacionado, estudio, importado];
            pie(app.axesTipo,vector_pie_tipo);
            legend(app.axesTipo,{'Relacionado', 'En estudio', 'Importado'},'Location','west')
               
              
            %procedencia_ = datos(:,"Pa_sDeProcedencia");
            %procedencia_ = table2array(procedencia_);
            %procedencia_ = string(procedencia_);
            %procedencia_(procedencia_ == "Estados Unidos de América") = "Estados Unidos";

            %arreglo2 = procedencia_(procedencia_~="Colombia");
             
            espana = 266;
            usa = 222;
            brasil = 61; 
            ecuador = 60;
            mexico = 52;
            panama = 35;
            turquia = 28;
            francia = 20;
            peru = 20;
            chile = 19;
                        
            paises5 = [chile, peru, francia, turquia, panama, mexico, ecuador, brasil, usa, espana];
            barh(app.axesPaises, paises5, 'FaceColor',[0 0.4470 0.7410],'EdgeColor',[0 1 1],'LineWidth',1.5);
            yticklabels(app.axesPaises,{'Puerto Rico', 'Francia', 'Jamaica', 'Brasil', 'Turquía', 'Panamá', 'México', 'Ecuador', 'Estados Unidos', 'España'})
            
            
            % Tasa de letalidad
            tasa_letalidad_total = round((fallecidos/total_confirmados)*100,2);
            app.TasadeletalidadTotalidadEditField.Value = strcat(string(tasa_letalidad_total),'%');
            
            % Tasa de recuperación
            %tasa_recuperacion_total = round((recuperados/total_confirmados)*100,2);
            %app.TasaderecuperacionTotalidadEditField.Value = strcat(string(tasa_recuperacion_total),'%');
            
            % Tasa de positividad
            %tasa_positividad_total = round((activos/total_confirmados)*100,2);
            %app.TasaderecuperacionTotalidadEditField_2.Value = strcat(string(tasa_positividad_total),'%');
            
            % Tasa de Mortalidad
            % tomado del DANE
            total_personas_colombia = 48258494;
            tasa_mortalidad = total_personas_colombia;
            
            % Inicializa Mapa
            %geobubble(app.PanelMapa1, 4.57,-74.29);
            geobubble(app.PanelArreglo, 4.57,-74.29);
            %geobubble(app.PanelMapa2, 4.57,-74.29);
        
            tabla = [-1.2061, -71.2627
            6.2453, -75.6259
            10.9447, -74.7940
            10.9379, -74.8097
            4.6153, -74.0968
            8.6014, -74.2018
            5.4439, -73.3227
            3.8180, -76.9920
            5.3077, -75.4202
            0.7201, -74.1198
            10.4464, -75.5147
            5.6923, -71.0105
            2.4011, -76.8282
            10.1960, -73.3188
            5.4756, -77.0439
            5.2822, -74.4576
            8.6750, -75.8811
            2.8458, -75.3123
            11.2828, -72.5366
            10.1771, -74.2266
            3.1254, -73.0337
            1.6461, -77.9477
            8.1191, -73.0239
            4.5042, -75.6838
            4.7950, -75.6596
            12.5823, -81.7073
            11.2415, -74.2064
            7.0752, -73.2317
            8.8194, -74.7363
            4.3539, -75.2402
            3.3775, -76.5117];

            % Genera Mapa1
            gx1 = geoaxes(app.PanelMapa1);
            %geoscatter(gx,lat,lon,A,C,'^')
            geoscatter(gx1,3.57,-73.29,2000,'m','filled');
            geobasemap(gx1,'darkwater') 
            
            % Genera Mapa2
            gx2 = geoaxes(app.PanelMapa2);
            %geoscatter(gx,lat,lon,A,C,'^')
            geoscatter(gx2, tabla(:,1),tabla(:,2),100,'b','filled');
            geobasemap(gx2,'bluegreen') 
                  
            plot_casos_colombia(app, reporte, otros_datos)        
        end

        % Value changed function: FallecidosEditField
        function FallecidosEditFieldValueChanged(app, event)
            app.FallecidosEditField.Value = value;          
        end

        % Value changed function: RecuperadosEditField
        function RecuperadosEditFieldValueChanged(app, event)
            app.RecuperadosEditField.Value = value;            
        end

        % Value changed function: EnCasaEditField
        function EnCasaEditFieldValueChanged(app, event)
            app.EnCasaEditField.Value = value;          
        end

        % Value changed function: HospitalEditField
        function HospitalEditFieldValueChanged(app, event)
            app.HospitalEditField.Value = value;           
        end

        % Value changed function: HospitalUCIEditField
        function HospitalUCIEditFieldValueChanged(app, event)
            app.HospitalUCIEditField.Value = value;           
        end

        % Value changed function: HombresEditField
        function HombresEditFieldValueChanged(app, event)
            app.HombresEditField.Value = value;           
        end

        % Value changed function: MujeresEditField
        function MujeresEditFieldValueChanged(app, event)
            app.MujeresEditField.Value = value;          
        end

        % Callback function
        function FechadereporteEditFieldValueChanged(app, event)
            
        end

        % Value changed function: FechaReporteEditField
        function FechaReporteEditFieldValueChanged(app, event)
            app.FechaReporteEditField.Value = value;          
        end

        % Value changed function: RelacionadoEditField
        function RelacionadoEditFieldValueChanged(app, event)
            app.RelacionadoEditField.Value = value;
        end

        % Value changed function: EnEstudioEditField
        function EnEstudioEditFieldValueChanged(app, event)
            app.EnEstudioEditField.Value = value;           
        end

        % Value changed function: ImportadoEditField
        function ImportadoEditFieldValueChanged(app, event)
            app.ImportadoEditField.Value = value;
        end

        % Callback function
        function UITableDisplayDataChanged(app, event)
            app.UITable.DisplayData = newDisplayData;
            
        end

        % Value changed function: ActivosEditField
        function ActivosEditFieldValueChanged(app, event)
            app.ActivosEditField.Value = value;            
        end

        % Value changed function: Menoresa35aosEditField
        function Menoresa35aosEditFieldValueChanged(app, event)
            app.Menoresa35aosEditField.Value = value;           
        end

        % Value changed function: Mayoresa35aosEditField
        function Mayoresa35aosEditFieldValueChanged(app, event)
            app.Mayoresa35aosEditField.Value = value;            
        end

        % Value changed function: LeveEditField
        function LeveEditFieldValueChanged(app, event)
            app.LeveEditField.Value = value;          
        end

        % Value changed function: ModeradoEditField
        function ModeradoEditFieldValueChanged(app, event)
            app.ModeradoEditField.Value = value;         
        end

        % Value changed function: GraveEditField
        function GraveEditFieldValueChanged(app, event)
            app.GraveEditField.Value = value;           
        end

        % Callback function
        function GenerarreporteButtonPushed(app, event)
         
        end

        % Value changed function: ConfirmadosDeparEditField
        function ConfirmadosDeparEditFieldValueChanged(app, event)
            app.ConfirmadosDeparEditField.Value = value;            
        end

        % Callback function
        function DepartamentoDropDownValueChanged(app, event)
           
        end

        % Value changed function: RecuperadosDeparEditField
        function RecuperadosDeparEditFieldValueChanged(app, event)
            app.RecuperadosDeparEditField.Value = value;           
        end

        % Value changed function: FallecidosDeparEditField
        function FallecidosDeparEditFieldValueChanged(app, event)
            app.FallecidosDeparEditField.Value = value;         
        end

        % Value changed function: DepartamentosEditField
        function DepartamentosEditFieldValueChanged(app, event)
            app.DepartamentosEditField.Value = value;            
        end

        % Value changed function: ListaDepartamentos
        function ListaDepartamentosValueChanged(app, event)
            selector = app.ListaDepartamentos.Value;
            
            datos = readtable("Casos_positivos_de_COVID-19_en_Colombia.csv");

            D_afectados = datos(:,"DepartamentoODistrito");
            D_afectados = table2array(D_afectados);
            D_afectados = string(D_afectados);     
            D_afectados(D_afectados == "Archipiélago de San Andrés Providencia y Santa Catalina") = "San Andrés";

        
            departamento_total = sum(count(D_afectados,string(selector)));        
            
            otros_datos = datos(:,"atenci_n");
            otros_datos = table2array(otros_datos);
            otros_datos = string(otros_datos);
            otros_datos(otros_datos == "casa") = "Casa";
            
            depa_otros = [otros_datos, D_afectados];
            depa = depa_otros((depa_otros(:,2)==string(selector)));
            
            depa_recu = depa((depa(:,1)=="Recuperado"));
            depa_recu = length(depa_recu);
            
            depa_falle = depa((depa(:,1)=="Fallecido"));
            depa_falle = length(depa_falle);
            
            depa_activo = departamento_total - depa_recu - depa_falle;
                    
            % Casos
            app.ConfirmadosDeparEditField.Value = departamento_total;
            app.RecuperadosDeparEditField.Value = depa_recu;
            app.FallecidosDeparEditField.Value = depa_falle;
            app.ActivosDeparEditField.Value = depa_activo;
            
            %%% Columna Sexo %%%
            genero = datos(:,"Sexo");
            genero = table2array(genero);
            genero = string(genero);
            genero(genero == "m") = "M";
            genero(genero == "f") = "F";
            
            genero_depa = [genero,D_afectados];

            generos_depa = genero_depa((genero_depa(:,2)==string(selector)));
            
            genero_depa_F = generos_depa((generos_depa(:,1)=="F"));
            genero_depa_F = length(genero_depa_F);
            
            genero_depa_M = generos_depa((generos_depa(:,1)=="M"));
            genero_depa_M = length(genero_depa_M);
   
            vector_pie_genero_depa = [genero_depa_M, genero_depa_F];
            pie(app.axesPieDepartamentos,vector_pie_genero_depa);
            legend(app.axesPieDepartamentos,{'Hombres', 'Mujeres'},'Location','northwest')
            
             % Hombres Depar
            app.HombresDeparEditField.Value = genero_depa_M;
            
            % Mujeres Depar

            app.MujeresDeparEditField.Value = genero_depa_F;
            
            % Porcentaje frente al total
            % Hombres
            hombres = count(genero,'M');
            hombres = sum(hombres);
            app.HombresEditField.Value = hombres;
            
           % Mujeres
            mujeres = count(genero,'F');
            mujeres = sum(mujeres);
            app.MujeresEditField.Value = mujeres;
           
            
            total_confirmados = hombres + mujeres;
                      
            porcen_departamento = (100*departamento_total)/(total_confirmados);
            porcen_restante = 100 - porcen_departamento;
            
            vector_porcent_depar = [porcen_restante, porcen_departamento];
            explode = [0 1];
            pie3(app.axesComparDepartamento,vector_porcent_depar,explode)
            
            texto_depart = strcat(string(selector),' VS Colombia');
            app.DeparEditField.Value = texto_depart;
            %title(app.axesComparDepartamento, texto_depart);

            % Tasa de letalidad
            tasa_letalidad = round((depa_falle/departamento_total)*100,2);
            app.TasadeletalidadDeparEditField.Value = strcat(string(tasa_letalidad),'%');
            
            % Edades
            edad = datos(:,"Edad");
            edad = table2array(edad);
            
            edades_depa = [edad,D_afectados];
            
            edad_depa = edades_depa((edades_depa(:,2)==string(selector)));
            edad_depa = str2double(edad_depa);

            e_09 = length(edad_depa((edad_depa<10)));
            e_1019 = length(edad_depa((edad_depa>=10 & edad_depa<20)));
            e_2029 = length(edad_depa((edad_depa>=20 & edad_depa<30)));
            e_3039 = length(edad_depa((edad_depa>=30 & edad_depa<40)));
            e_4049 = length(edad_depa((edad_depa>=40 & edad_depa<50)));
            e_5059 = length(edad_depa((edad_depa>=50 & edad_depa<60)));
            e_6069 = length(edad_depa((edad_depa>=60 & edad_depa<70)));
            e_7079 = length(edad_depa((edad_depa>=70 & edad_depa<80)));
            e_8089 = length(edad_depa((edad_depa>=80 & edad_depa<90)));
            e_9099 = length(edad_depa((edad_depa>=90 & edad_depa<110)));
               
            v_edades_depa = [e_9099; e_8089; e_7079; e_6069; e_5059; e_4049; e_3039; e_2029; e_1019; e_09];
            barh(app.axesEdadesDepartamentos,v_edades_depa, 'FaceColor',[0 .5 .5], 'EdgeColor',[0 1 0],'LineWidth',1.5);
            yticklabels(app.axesEdadesDepartamentos,{'90-99', '80-89', '70-79', '60-69', '50-59', '40-49', '30-39', '20-29', '10-19', '0-9'})
            
            
            % Atencion Departamento
            atencion_depa = [otros_datos,D_afectados];

            atencion_depa = atencion_depa((atencion_depa(:,2)==string(selector)));
            
            % En Casa
            atencion_depa_C = atencion_depa((atencion_depa(:,1)=="Casa"));
            atencion_depa_C = length(atencion_depa_C);
            app.EnCasaDeparEditField.Value = atencion_depa_C;
            
            % En UCI
            atencion_depa_U = atencion_depa((atencion_depa(:,1)=="Hospital UCI"));
            atencion_depa_U = length(atencion_depa_U);
            app.HospitalUCIDeparEditField.Value = atencion_depa_U;
            
            % En Hospital
            atencion_depa_H = depa_activo - atencion_depa_C - atencion_depa_U;
            app.HospitalDeparEditField.Value = atencion_depa_H;

            vector_pie_estancia_depar = [atencion_depa_C, atencion_depa_H, atencion_depa_U];
            %pie(app.axesAtencionActivosDepar,vector_pie_estancia_depar);
            %legend(app.axesAtencionActivosDepar,{'En Casa', 'Hospital', 'Hospital UCI'},'Location','west')
            
            bar(app.axesAtencionPacienteDepar,vector_pie_estancia_depar,'stacked','FaceColor',[0.8500 0.3250 0.0980],'EdgeColor',[0 1 1],'LineWidth',1.5);
            xticklabels(app.axesAtencionPacienteDepar,{'En Casa', 'Hospital', 'Hospital UCI'})

        
            % Estado Departamento
            estado = datos(:,"Estado");
            estado = table2array(estado);
            estado = string(estado);
            estado(estado == "leve") = "Leve";

            estado_depa = [estado,D_afectados];

            estado_depa = estado_depa((estado_depa(:,2)==string(selector)));
            
            % Moderado
            moderado_depa = estado_depa((estado_depa(:,1)=="Moderado"));
            moderado_depa = length(moderado_depa);
            app.ModeradoDeparEditField.Value = moderado_depa;
            
            % Grave
            grave_depa = estado_depa((estado_depa(:,1)=="Grave"));
            grave_depa = length(grave_depa);
            app.GraveDeparEditField.Value = grave_depa;
            
            % Leve
            leve_depa = departamento_total - depa_recu - depa_falle - moderado_depa - grave_depa;
            app.LeveDeparEditField.Value  = leve_depa;
            
            %vector_pie_estado_depar = [leve_depa, moderado_depa, grave_depa, depa_recu, depa_falle];
            vector_pie_estado_depar = [leve_depa, moderado_depa, grave_depa];
            %pie3(app.axesEstadoPacienteDepar,vector_pie_estado_depar);
            %legend(app.axesEstadoPacienteDepar,{'Leve', 'Moderado', 'Grave', 'Recuperado', 'Fallecido'},'Location','west')
            
            bar(app.axesEstadoPacienteDepar,vector_pie_estado_depar,'stacked','EdgeColor',[0 1 1],'LineWidth',1.5);
            xticklabels(app.axesEstadoPacienteDepar,{'Leve', 'Moderado', 'Grave'})
            
            % Pie 
            vector_pie_casos_depa = [depa_activo, depa_recu, depa_falle];
            pie(app.axesCasosDepa,vector_pie_casos_depa);
            legend(app.axesCasosDepa,{'Activos', 'Recuperados', 'Fallecidos'},'Location','southoutside')


        end

        % Callback function
        function versusDepaValueChanged(app, event)
            app.versusDepa.Value = value;
            
        end

        % Value changed function: DeparEditField
        function DeparEditFieldValueChanged(app, event)
            app.DeparEditField.Value = value;          
        end

        % Callback function
        function tasadeletalidadEditFieldValueChanged(app, event)
            app.tasadeletalidadEditField.Value = value;           
        end

        % Value changed function: TasadeletalidadDeparEditField
        function TasadeletalidadDeparEditFieldValueChanged(app, event)
            app.TasadeletalidadDeparEditField.Value = value;
        end

        % Value changed function: ActivosDeparEditField
        function ActivosDeparEditFieldValueChanged(app, event)
            app.ActivosDeparEditField.Value = value;         
        end

        % Value changed function: CargandoPlot
        function CargandoPlotValueChanged(app, event)
            app.CargandoPlot.Value = value;
            
        end

        % Button pushed function: GenerarlacurvaButton
        function GenerarlacurvaButtonPushed(app, event)
            selector = app.ListaDepartamentos.Value;
            datos = readtable("Casos_positivos_de_COVID-19_en_Colombia.csv");

            reporte = datos(:,"fechaReporteWeb");
            reporte = table2array(reporte);
            reporte = string(reporte);
            reporte = split(reporte,[" ","T",":"]);
            reporte = reporte(:,1);
            % por que la cagaron
            reporte(reporte == "-") = "2020-04-24";
            reporte = reporte(:,1);
            
            D_afectados = datos(:,"DepartamentoODistrito");
            D_afectados = table2array(D_afectados);
            D_afectados = string(D_afectados);
            D_afectados(D_afectados == "Archipiélago de San Andrés Providencia y Santa Catalina") = "San Andrés";
            
            fechas_arreglo = unique(reporte);

            fechas_arreglo_time = datetime(fechas_arreglo);
            dt = caldiff(fechas_arreglo_time,'days');
            dt = string(dt); dt2 = dt.split("d"); dt2 = dt2(:,1); dt2 = str2double(dt2);
            
            cont = 0;
            for i = 1:length(dt2)
                if (dt2(i) > 1)
                    for j = 1:1:(dt2(i))
                        cont = cont + 1;
                        fechas_arreglo_fix(cont,1) = datetime(fechas_arreglo(i))+(j-1);
                    end
                elseif (dt2(i) == 1)
                    cont = cont + 1;
                    fechas_arreglo_fix(cont,1) = datetime(fechas_arreglo(i));
                end
                   
            end
            fechas_arreglo_fix(cont+1,1) = fechas_arreglo(end,1);  % warning (!)
            
            
            departamentos = [reporte, D_afectados];

            departamento_curva = departamentos((departamentos(:,2)==string(selector)));

            [C4,Curva_depa] = unique(departamento_curva, 'last', 'legacy');

            fix_depa = datetime(C4)+1;
            fix_depa = fix_depa-1;
            
            
            depa_arreglo_fix = zeros(length(fechas_arreglo_fix),1);
            for i = 1:length(C4)
                for j = 1:length(fechas_arreglo_fix)               
                    if (string(fix_depa(i,1)) == fechas_arreglo_fix(j,1))
                        depa_arreglo_fix(j,1) = Curva_depa(i,1);
                        break
                    end
                               
                end
            end
                        
            for i = 1:length(depa_arreglo_fix)             
                if (depa_arreglo_fix(i,1) == 0 && i > 1)
                    depa_arreglo_fix(i,1) = depa_arreglo_fix(i-1,1);
                end
            end
            
            dates_ejex = 1:length(depa_arreglo_fix); 
            plot(app.axesDepartamentos,dates_ejex, depa_arreglo_fix, 'b');
            legend(app.axesDepartamentos,'Confirmados','Location','northwest')

        end

        % Value changing function: CargandoPlot
        function CargandoPlotValueChanging(app, event)
            %changingValue = event.Value;
            
        end

        % Value changed function: EnCasaDeparEditField
        function EnCasaDeparEditFieldValueChanged(app, event)
            app.EnCasaDeparEditField.Value = value;            
        end

        % Value changed function: HospitalDeparEditField
        function HospitalDeparEditFieldValueChanged(app, event)
            app.HospitalDeparEditField.Value = value;            
        end

        % Value changed function: HospitalUCIDeparEditField
        function HospitalUCIDeparEditFieldValueChanged(app, event)
            app.HospitalUCIDeparEditField.Value = value;          
        end

        % Value changed function: LeveDeparEditField
        function LeveDeparEditFieldValueChanged(app, event)
            app.LeveDeparEditField.Value = value;            
        end

        % Value changed function: ModeradoDeparEditField
        function ModeradoDeparEditFieldValueChanged(app, event)
            app.ModeradoDeparEditField.Value = value;           
        end

        % Value changed function: GraveDeparEditField
        function GraveDeparEditFieldValueChanged(app, event)
            app.GraveDeparEditField.Value = value;            
        end

        % Callback function
        function ListBoxValueChanged(app, event)
            selector = app.ListBox.Value;


        end

        % Value changed function: PrimerCasoConfirmadoEditField
        function PrimerCasoConfirmadoEditFieldValueChanged(app, event)
            app.PrimerCasoConfirmadoEditField.Value = value;           
        end

        % Display data changed function: UITable
        function UITableDisplayDataChanged2(app, event)
            app.UITable.DisplayData = value;
                      
        end

        % Callback function
        function TipodeModeloDropDownValueChanged(app, event)
            selector = app.TipodeModeloDropDown.Value;
            
            
        end

        % Callback function
        function DasProyeccinSliderValueChanged(app, event)
           app.DasProyeccinSlider.Value;
            
        end

        % Value changed function: valorde_a
        function valorde_aValueChanged(app, event)
            app.valorde_a.Value = value;
            
        end

        % Value changed function: valorde_b
        function valorde_bValueChanged(app, event)
            app.valorde_b.Value = value;
            
        end

        % Value changed function: R2EditField_2
        function R2EditField_2ValueChanged(app, event)
            app.R2EditField_2.Value = value;
            
        end

        % Value changed function: RMSEEditField
        function RMSEEditFieldValueChanged(app, event)
            app.RMSEEditField.Value = value;
            
        end

        % Callback function
        function daEditFieldValueChanged(app, event)
            app.daEditField.Value = value;
            
        end

        % Size changed function: Exponencial1Tab
        function DasProyeccinSliderValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Value changed function: ListaCiudades
        function ListaCiudadesValueChanged(app, event)
            selector = app.ListaCiudades.Value;
            
            datos = readtable("Casos_positivos_de_COVID-19_en_Colombia.csv");

              
            C_afectados = datos(:,"CiudadDeUbicaci_n");
            C_afectados = table2array(C_afectados);
            C_afectados = string(C_afectados);


            otros_datos = datos(:,"atenci_n");
            otros_datos = table2array(otros_datos);
            otros_datos = string(otros_datos);
            otros_datos(otros_datos == "casa") = "Casa";
            
            departamento_total = sum(count(C_afectados,string(selector)));  
            
            
            depa_otros = [otros_datos, C_afectados];
            depa = depa_otros((depa_otros(:,2)==string(selector)));
            
            depa_recu = depa((depa(:,1)=="Recuperado"));
            depa_recu = length(depa_recu);
            
            depa_falle = depa((depa(:,1)=="Fallecido"));
            depa_falle = length(depa_falle);
            
            depa_activo = departamento_total - depa_recu - depa_falle;

            
            % Casos
            app.ConfirmadosDeparEditField_2.Value = departamento_total;
            app.RecuperadosDeparEditField_2.Value = depa_recu;
            app.FallecidosDeparEditField_2.Value  = depa_falle;
            %app.ActivosDeparEditField.Value = depa_activo;
            
            % Atencion Departamento
            atencion_depa = [otros_datos,C_afectados];

            atencion_depa = atencion_depa((atencion_depa(:,2)==string(selector)));

            % En Casa
            atencion_depa_C = atencion_depa((atencion_depa(:,1)=="Casa"));
            atencion_depa_C = length(atencion_depa_C);
            app.EnCasaEditField_2.Value = atencion_depa_C;
            
            % En UCI
            atencion_depa_U = atencion_depa((atencion_depa(:,1)=="Hospital UCI"));
            atencion_depa_U = length(atencion_depa_U);
            app.HospitalUCIEditField_2.Value = atencion_depa_U;
            
            % En Hospital
            atencion_depa_H = depa_activo - atencion_depa_C - atencion_depa_U;
            app.HospitalEditField_2.Value = atencion_depa_H;

            vector_pie_estancia_depar = [atencion_depa_C, atencion_depa_H, atencion_depa_U];
            %pie(app.axesAtencionActivosDepar,vector_pie_estancia_depar);
            %legend(app.axesAtencionActivosDepar,{'En Casa', 'Hospital', 'Hospital UCI'},'Location','west')
            
            bar(app.axesAtencionPacienteDepar_2,vector_pie_estancia_depar,'stacked','FaceColor',[0.8500 0.3250 0.0980],'EdgeColor',[0 1 1],'LineWidth',1.5);
            xticklabels(app.axesAtencionPacienteDepar_2,{'En Casa', 'Hospital', 'Hospital UCI'})
            
            
            % Estado Departamento
            estado = datos(:,"Estado");
            estado = table2array(estado);
            estado = string(estado);
            estado(estado == "leve") = "Leve";

            estado_depa = [estado,C_afectados];

            estado_depa = estado_depa((estado_depa(:,2)==string(selector)));
            
            % Moderado
            moderado_depa = estado_depa((estado_depa(:,1)=="Moderado"));
            moderado_depa = length(moderado_depa);
            app.ModeradoEditField_2.Value = moderado_depa;
            
            % Grave
            grave_depa = estado_depa((estado_depa(:,1)=="Grave"));
            grave_depa = length(grave_depa);
            app.GraveEditField_2.Value = grave_depa;
            
            % Leve
            leve_depa = departamento_total - depa_recu - depa_falle - moderado_depa - grave_depa;
            app.LeveEditField_2.Value  = leve_depa;
            
            %vector_pie_estado_depar = [leve_depa, moderado_depa, grave_depa, depa_recu, depa_falle];
            vector_pie_estado_depar = [leve_depa, moderado_depa, grave_depa];
            %pie3(app.axesEstadoPacienteDepar,vector_pie_estado_depar);
            %legend(app.axesEstadoPacienteDepar,{'Leve', 'Moderado', 'Grave', 'Recuperado', 'Fallecido'},'Location','west')
            
            bar(app.axesEstadoPacienteDepar_2,vector_pie_estado_depar,'stacked','EdgeColor',[0 1 1],'LineWidth',1.5);
            xticklabels(app.axesEstadoPacienteDepar_2,{'Leve', 'Moderado', 'Grave'})
            
            % Edades
            edad = datos(:,"Edad");
            edad = table2array(edad);
           
            
            edades_depa = [edad,C_afectados];
            
            edad_depa = edades_depa((edades_depa(:,2)==string(selector)));
            edad_depa = str2double(edad_depa);

            e_09 = length(edad_depa((edad_depa<10)));
            e_1019 = length(edad_depa((edad_depa>=10 & edad_depa<20)));
            e_2029 = length(edad_depa((edad_depa>=20 & edad_depa<30)));
            e_3039 = length(edad_depa((edad_depa>=30 & edad_depa<40)));
            e_4049 = length(edad_depa((edad_depa>=40 & edad_depa<50)));
            e_5059 = length(edad_depa((edad_depa>=50 & edad_depa<60)));
            e_6069 = length(edad_depa((edad_depa>=60 & edad_depa<70)));
            e_7079 = length(edad_depa((edad_depa>=70 & edad_depa<80)));
            e_8089 = length(edad_depa((edad_depa>=80 & edad_depa<90)));
            e_9099 = length(edad_depa((edad_depa>=90 & edad_depa<110)));
               
            v_edades_depa = [e_9099; e_8089; e_7079; e_6069; e_5059; e_4049; e_3039; e_2029; e_1019; e_09];
            barh(app.axesEstadoPacienteDepar_3,v_edades_depa, 'FaceColor',[0 .5 .5], 'EdgeColor',[0 1 0],'LineWidth',1.5);
            yticklabels(app.axesEstadoPacienteDepar_3,{'90-99', '80-89', '70-79', '60-69', '50-59', '40-49', '30-39', '20-29', '10-19', '0-9'})
            
            %% Mayores y menores
  
            menores_35 = edad_depa(edad_depa <= 35);
            menores_35 = length(menores_35);
            mayores_35 = edad_depa(edad_depa > 35);
            mayores_35 = length(mayores_35);
            
            app.Menoresa35aosEditField_2.Value = menores_35;
            app.Mayoresa35aosEditField_2.Value = mayores_35;
            
            %%% Columna Sexo %%%
            genero = datos(:,"Sexo");
            genero = table2array(genero);
            genero = string(genero);
            genero(genero == "m") = "M";
            genero(genero == "f") = "F";
            
            genero_depa = [genero,C_afectados];

            generos_depa = genero_depa((genero_depa(:,2)==string(selector)));
            
            genero_depa_F = generos_depa((generos_depa(:,1)=="F"));
            genero_depa_F = length(genero_depa_F);
            
            genero_depa_M = generos_depa((generos_depa(:,1)=="M"));
            genero_depa_M = length(genero_depa_M);
   
            vector_pie_genero_depa = [genero_depa_M, genero_depa_F];
            pie(app.axesEstadoPacienteDepar_4,vector_pie_genero_depa);
            legend(app.axesEstadoPacienteDepar_4,{'Hombres', 'Mujeres'},'Location','northwest')
            
             % Hombres Depar
            app.HombresDeparEditField_2.Value = genero_depa_M;
            
            % Mujeres Depar

            app.MujeresDeparEditField_2.Value = genero_depa_F;
            
            
            % Porcentaje frente al total
            % Hombres
            hombres = count(genero,'M');
            hombres = sum(hombres);
            app.HombresEditField.Value = hombres;
            
           % Mujeres
            mujeres = count(genero,'F');
            mujeres = sum(mujeres);
            app.MujeresEditField.Value = mujeres;
           
            
            total_confirmados = hombres + mujeres;
            
            porcen_departamento = (100*departamento_total)/(total_confirmados);
            porcen_restante = 100 - porcen_departamento;
            
            vector_porcent_depar = [porcen_restante, porcen_departamento];
            explode = [0 1];
            pie3(app.axesEstadoPacienteDepar_5,vector_porcent_depar,explode)
            
            texto_depart = strcat(string(selector),' VS Colombia');
            app.DeparEditField_2.Value = texto_depart;
            
             % Tasa de letalidad
            tasa_letalidad = round((depa_falle/departamento_total)*100,2);
            app.TasadeletalidadDeparEditField_2.Value = strcat(string(tasa_letalidad),'%');
            
        end

        % Value changed function: ConfirmadosDeparEditField_2
        function ConfirmadosDeparEditField_2ValueChanged(app, event)
            app.ConfirmadosDeparEditField_2.Value = value;
            
        end

        % Value changed function: RecuperadosDeparEditField_2
        function RecuperadosDeparEditField_2ValueChanged(app, event)
            app.RecuperadosDeparEditField_2.Value = value;
            
        end

        % Value changed function: FallecidosDeparEditField_2
        function FallecidosDeparEditField_2ValueChanged(app, event)
            app.FallecidosDeparEditField_2.Value = value;
            
        end

        % Value changed function: EnCasaEditField_2
        function EnCasaEditField_2ValueChanged(app, event)
            app.EnCasaEditField_2.Value = value;
            
        end

        % Value changed function: HospitalEditField_2
        function HospitalEditField_2ValueChanged(app, event)
            app.HospitalEditField_2.Value = value;
            
        end

        % Value changed function: HospitalUCIEditField_2
        function HospitalUCIEditField_2ValueChanged(app, event)
            app.HospitalUCIEditField_2.Value = value;
            
        end

        % Value changed function: LeveEditField_2
        function LeveEditField_2ValueChanged(app, event)
            app.LeveEditField_2.Value = value;
            
        end

        % Value changed function: ModeradoEditField_2
        function ModeradoEditField_2ValueChanged(app, event)
            app.ModeradoEditField_2.Value = value;
            
        end

        % Value changed function: GraveEditField_2
        function GraveEditField_2ValueChanged(app, event)
            app.GraveEditField_2.Value = value;
            
        end

        % Value changed function: HombresDeparEditField_2
        function HombresDeparEditField_2ValueChanged(app, event)
            app.HombresDeparEditField_2.Value = value;
            
        end

        % Value changed function: MujeresDeparEditField_2
        function MujeresDeparEditField_2ValueChanged(app, event)
            app.MujeresDeparEditField_2.Value = value;
            
        end

        % Value changed function: Menoresa35aosEditField_2
        function Menoresa35aosEditField_2ValueChanged(app, event)
            app.Menoresa35aosEditField_2.Value = value;
            
        end

        % Value changed function: Mayoresa35aosEditField_2
        function Mayoresa35aosEditField_2ValueChanged(app, event)
            app.Mayoresa35aosEditField_2.Value = value;
            
        end

        % Value changed function: MunicipiosEditField
        function MunicipiosEditFieldValueChanged(app, event)
            app.MunicipiosEditField.Value = value;
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Button pushed function: httpsgithubcomcristianrubioaButton
        function httpsgithubcomcristianrubioaButtonPushed(app, event)
            dos('explorer https://github.com/cristianrubioa'); 
            web('https://github.com/cristianrubioa'); 
        end

        % Button pushed function: 
        % httpswwwlinkedincomincristianrubioaguiarButton
        function httpswwwlinkedincomincristianrubioaguiarButtonPushed(app, event)
            dos('explorer https://www.linkedin.com/in/cristianrubioaguiar'); 
            web('https://www.linkedin.com/in/cristianrubioaguiar'); 
        end

        % Button pushed function: 
        % httpswwwresearchgatenetprofileJose_FernandezGallegoButton
        function httpswwwresearchgatenetprofileJose_FernandezGallegoButtonPushed(app, event)
            dos('explorer https://www.researchgate.net/profile/Jose_Fernandez-Gallego'); 
            web('https://www.researchgate.net/profile/Jose_Fernandez-Gallego'); 
        end

        % Button pushed function: httpcristianrubioagmailcomButton
        function httpcristianrubioagmailcomButtonPushed(app, event)
            dos('explorer http://cristianrubioa@gmail.com'); 
            web('http://cristianrubioa@gmail.com'); 
        end

        % Button pushed function: 
        % httpjosefernandezunibagueeducoButton
        function httpjosefernandezunibagueeducoButtonPushed(app, event)
            dos('explorer http://jose.fernandez@unibague.edu.co'); 
            web('http://jose.fernandez@unibague.edu.co'); 
        end

        % Callback function
        function imagenButtonPushed(app, event)
            dos('explorer https://drive.google.com/open?id=1RhCBKPL2DXtOEcIzDgjBJP-eeDx6z9B_'); 
            web('https://drive.google.com/open?id=1RhCBKPL2DXtOEcIzDgjBJP-eeDx6z9B_'); 
            
        end

        % Callback function
        function imagenButton_2Pushed(app, event)
            dos('explorer https://drive.google.com/open?id=1ITeqwniyTJnpJVHvPBjeAJ8BuxoNfq5-'); 
            web('https://drive.google.com/open?id=1ITeqwniyTJnpJVHvPBjeAJ8BuxoNfq5-'); 
        end

        % Callback function
        function ButtonPushed(app, event)
            dos('explorer https://drive.google.com/open?id=14O3_xnGGJSRGK3Djc7m2xFM6JSqH7n_m'); 
            web('https://drive.google.com/open?id=14O3_xnGGJSRGK3Djc7m2xFM6JSqH7n_m'); 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.BoletndeinformacinCOVID19COLOMBIALabel = uilabel(app.UIFigure);
            app.BoletndeinformacinCOVID19COLOMBIALabel.Text = '   Boletín de información COVID-19 (COLOMBIA)';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.NacionalTab = uitab(app.TabGroup);
            app.NacionalTab.Title = 'Nacional';
            app.GenerardatosdelboletnButton = uibutton(app.NacionalTab, 'push');
            app.GenerardatosdelboletnButton.ButtonPushedFcn = createCallbackFcn(app, @GenerardatosdelboletnButtonPushed, true);
            app.GenerardatosdelboletnButton.Text = 'Generar datos del boletín';
            app.UIAxes = uiaxes(app.NacionalTab);
            title(app.UIAxes, 'Casos confirmados acumulados')
            xlabel(app.UIAxes, 'Días')
            ylabel(app.UIAxes, 'Número de confirmados')
            app.UIAxes_2 = uiaxes(app.NacionalTab);
            title(app.UIAxes_2, 'Casos recuperados y muertos acumulados')
            xlabel(app.UIAxes_2, 'Días')
            ylabel(app.UIAxes_2, 'Número de fallecidos')
            app.UIAxes_3 = uiaxes(app.NacionalTab);
            title(app.UIAxes_3, 'Casos confirmados y activos acumulados')
            xlabel(app.UIAxes_3, 'Días')
            ylabel(app.UIAxes_3, 'Número de recuperados')
            app.TabGroup2 = uitabgroup(app.NacionalTab);
            app.AtencinyEstadoTab = uitab(app.TabGroup2);
            app.AtencinyEstadoTab.Title = 'Atención y Estado';
            app.TabGroup3 = uitabgroup(app.AtencinyEstadoTab);
            app.ConfirmadosTab = uitab(app.TabGroup3);
            app.ConfirmadosTab.Title = 'Confirmados';
            app.axesAtencionConfirmados = uiaxes(app.ConfirmadosTab);
            title(app.axesAtencionConfirmados, '')
            xlabel(app.axesAtencionConfirmados, '')
            ylabel(app.axesAtencionConfirmados, '')
            app.axesAtencionConfirmados.Visible = 'off';
            app.FallecidosEditFieldLabel = uilabel(app.ConfirmadosTab);
            app.FallecidosEditFieldLabel.Text = 'Fallecidos';
            app.FallecidosEditField = uieditfield(app.ConfirmadosTab, 'numeric');
            app.FallecidosEditField.ValueChangedFcn = createCallbackFcn(app, @FallecidosEditFieldValueChanged, true);
            app.RecuperadosEditFieldLabel = uilabel(app.ConfirmadosTab);
            app.RecuperadosEditFieldLabel.Text = 'Recuperados ';
            app.RecuperadosEditField = uieditfield(app.ConfirmadosTab, 'numeric');
            app.RecuperadosEditField.ValueChangedFcn = createCallbackFcn(app, @RecuperadosEditFieldValueChanged, true);
            app.ActivosEditFieldLabel = uilabel(app.ConfirmadosTab);
            app.ActivosEditFieldLabel.Text = 'Activos';
            app.ActivosEditField = uieditfield(app.ConfirmadosTab, 'numeric');
            app.ActivosEditField.ValueChangedFcn = createCallbackFcn(app, @ActivosEditFieldValueChanged, true);
            app.ActivosTab = uitab(app.TabGroup3);
            app.ActivosTab.Title = 'Activos';
            app.EnCasaEditFieldLabel = uilabel(app.ActivosTab);
            app.EnCasaEditFieldLabel.Text = 'En Casa';
            app.EnCasaEditField = uieditfield(app.ActivosTab, 'numeric');
            app.EnCasaEditField.ValueChangedFcn = createCallbackFcn(app, @EnCasaEditFieldValueChanged, true);
            app.HospitalEditFieldLabel = uilabel(app.ActivosTab);
            app.HospitalEditFieldLabel.Text = 'Hospital';
            app.HospitalEditField = uieditfield(app.ActivosTab, 'numeric');
            app.HospitalEditField.ValueChangedFcn = createCallbackFcn(app, @HospitalEditFieldValueChanged, true);
            app.HospitalUCIEditFieldLabel = uilabel(app.ActivosTab);
            app.HospitalUCIEditFieldLabel.Text = 'Hospital UCI';
            app.HospitalUCIEditField = uieditfield(app.ActivosTab, 'numeric');
            app.HospitalUCIEditField.ValueChangedFcn = createCallbackFcn(app, @HospitalUCIEditFieldValueChanged, true);
            app.axesAtencionActivos = uiaxes(app.ActivosTab);
            title(app.axesAtencionActivos, '')
            xlabel(app.axesAtencionActivos, '')
            ylabel(app.axesAtencionActivos, '')
            app.axesAtencionActivos.Visible = 'off';
            app.axesEstadoPaciente = uiaxes(app.AtencinyEstadoTab);
            title(app.axesEstadoPaciente, {'Estado de casos'; ''})
            xlabel(app.axesEstadoPaciente, '')
            ylabel(app.axesEstadoPaciente, '')
            app.axesEstadoPaciente.Visible = 'off';
            app.LeveEditFieldLabel = uilabel(app.AtencinyEstadoTab);
            app.LeveEditFieldLabel.Text = 'Leve';
            app.LeveEditField = uieditfield(app.AtencinyEstadoTab, 'numeric');
            app.LeveEditField.ValueChangedFcn = createCallbackFcn(app, @LeveEditFieldValueChanged, true);
            app.ModeradoEditFieldLabel = uilabel(app.AtencinyEstadoTab);
            app.ModeradoEditFieldLabel.Text = 'Moderado';
            app.ModeradoEditField = uieditfield(app.AtencinyEstadoTab, 'numeric');
            app.ModeradoEditField.ValueChangedFcn = createCallbackFcn(app, @ModeradoEditFieldValueChanged, true);
            app.GraveEditFieldLabel = uilabel(app.AtencinyEstadoTab);
            app.GraveEditFieldLabel.Text = 'Grave';
            app.GraveEditField = uieditfield(app.AtencinyEstadoTab, 'numeric');
            app.GraveEditField.ValueChangedFcn = createCallbackFcn(app, @GraveEditFieldValueChanged, true);
            app.TipoyProcedenciaTab = uitab(app.TabGroup2);
            app.TipoyProcedenciaTab.Title = 'Tipo y Procedencia';
            app.axesTipo = uiaxes(app.TipoyProcedenciaTab);
            title(app.axesTipo, {'Tipo Importación'; ''})
            xlabel(app.axesTipo, '')
            ylabel(app.axesTipo, '')
            app.axesTipo.Visible = 'off';
            app.RelacionadoEditFieldLabel = uilabel(app.TipoyProcedenciaTab);
            app.RelacionadoEditFieldLabel.Text = 'Relacionado';
            app.RelacionadoEditField = uieditfield(app.TipoyProcedenciaTab, 'numeric');
            app.RelacionadoEditField.ValueChangedFcn = createCallbackFcn(app, @RelacionadoEditFieldValueChanged, true);
            app.EnEstudioEditFieldLabel = uilabel(app.TipoyProcedenciaTab);
            app.EnEstudioEditFieldLabel.Text = 'En Estudio';
            app.EnEstudioEditField = uieditfield(app.TipoyProcedenciaTab, 'numeric');
            app.EnEstudioEditField.ValueChangedFcn = createCallbackFcn(app, @EnEstudioEditFieldValueChanged, true);
            app.ImportadoEditFieldLabel = uilabel(app.TipoyProcedenciaTab);
            app.ImportadoEditFieldLabel.Text = 'Importado';
            app.ImportadoEditField = uieditfield(app.TipoyProcedenciaTab, 'numeric');
            app.ImportadoEditField.ValueChangedFcn = createCallbackFcn(app, @ImportadoEditFieldValueChanged, true);
            app.axesPaises = uiaxes(app.TipoyProcedenciaTab);
            title(app.axesPaises, {'Top 10 - País de procedencia'; ''})
            xlabel(app.axesPaises, '')
            ylabel(app.axesPaises, '')
            app.EdadesyGneroTab = uitab(app.TabGroup2);
            app.EdadesyGneroTab.Title = 'Edades y Género';
            app.axesGenero = uiaxes(app.EdadesyGneroTab);
            title(app.axesGenero, {'Distribución por Género'; ''})
            xlabel(app.axesGenero, '')
            ylabel(app.axesGenero, '')
            app.axesGenero.Visible = 'off';
            app.HombresEditFieldLabel = uilabel(app.EdadesyGneroTab);
            app.HombresEditFieldLabel.Text = 'Hombres';
            app.HombresEditField = uieditfield(app.EdadesyGneroTab, 'numeric');
            app.HombresEditField.ValueChangedFcn = createCallbackFcn(app, @HombresEditFieldValueChanged, true);
            app.MujeresEditFieldLabel = uilabel(app.EdadesyGneroTab);
            app.MujeresEditFieldLabel.Text = 'Mujeres';
            app.MujeresEditField = uieditfield(app.EdadesyGneroTab, 'numeric');
            app.MujeresEditField.ValueChangedFcn = createCallbackFcn(app, @MujeresEditFieldValueChanged, true);
            app.Menoresa35aosEditFieldLabel = uilabel(app.EdadesyGneroTab);
            app.Menoresa35aosEditFieldLabel.Text = 'Menores a 35 años';
            app.Menoresa35aosEditField = uieditfield(app.EdadesyGneroTab, 'numeric');
            app.Menoresa35aosEditField.ValueChangedFcn = createCallbackFcn(app, @Menoresa35aosEditFieldValueChanged, true);
            app.Mayoresa35aosEditFieldLabel = uilabel(app.EdadesyGneroTab);
            app.Mayoresa35aosEditFieldLabel.Text = 'Mayores a 35 años';
            app.Mayoresa35aosEditField = uieditfield(app.EdadesyGneroTab, 'numeric');
            app.Mayoresa35aosEditField.ValueChangedFcn = createCallbackFcn(app, @Mayoresa35aosEditFieldValueChanged, true);
            app.axesRangoEdades = uiaxes(app.EdadesyGneroTab);
            title(app.axesRangoEdades, {'Distribución por edad'; ''})
            xlabel(app.axesRangoEdades, '')
            ylabel(app.axesRangoEdades, '')
            app.MapadeCasosTab = uitab(app.TabGroup2);
            app.MapadeCasosTab.Title = 'Mapa de Casos';
            app.PanelMapa1 = uipanel(app.MapadeCasosTab);
            app.PanelMapa2 = uipanel(app.MapadeCasosTab);
            app.ModelosdePrediccinTab = uitab(app.TabGroup2);
            app.ModelosdePrediccinTab.Title = 'Modelos de Predicción';
            app.TabGroup3_2 = uitabgroup(app.ModelosdePrediccinTab);
            app.Exponencial1Tab = uitab(app.TabGroup3_2);
            app.Exponencial1Tab.SizeChangedFcn = createCallbackFcn(app, @DasProyeccinSliderValueChanging, true);
            app.Exponencial1Tab.Title = 'Exponencial1';
            app.axesModeloExponencial = uiaxes(app.Exponencial1Tab);
            title(app.axesModeloExponencial, 'Modelo Exponencial2')
            xlabel(app.axesModeloExponencial, 'Días')
            ylabel(app.axesModeloExponencial, 'Número de confirmados')
            app.aEditFieldLabel = uilabel(app.Exponencial1Tab);
            app.aEditFieldLabel.Text = 'a';
            app.valorde_a = uieditfield(app.Exponencial1Tab, 'numeric');
            app.valorde_a.ValueChangedFcn = createCallbackFcn(app, @valorde_aValueChanged, true);
            app.bEditFieldLabel = uilabel(app.Exponencial1Tab);
            app.bEditFieldLabel.Text = 'b';
            app.valorde_b = uieditfield(app.Exponencial1Tab, 'numeric');
            app.valorde_b.ValueChangedFcn = createCallbackFcn(app, @valorde_bValueChanged, true);
            app.fxaexpbxLabel = uilabel(app.Exponencial1Tab);
            app.fxaexpbxLabel.Text = ' f(x) = a*exp(b*x)';
            app.R2EditField_2Label = uilabel(app.Exponencial1Tab);
            app.R2EditField_2Label.Text = 'R^2';
            app.R2EditField_2 = uieditfield(app.Exponencial1Tab, 'numeric');
            app.R2EditField_2.ValueChangedFcn = createCallbackFcn(app, @R2EditField_2ValueChanged, true);
            app.RMSEEditFieldLabel = uilabel(app.Exponencial1Tab);
            app.RMSEEditFieldLabel.Text = 'RMSE';
            app.RMSEEditField = uieditfield(app.Exponencial1Tab, 'numeric');
            app.RMSEEditField.ValueChangedFcn = createCallbackFcn(app, @RMSEEditFieldValueChanged, true);
            app.ProyeccindecasosLabel = uilabel(app.Exponencial1Tab);
            app.ProyeccindecasosLabel.Text = 'Proyección de casos';
            app.UITable2 = uitable(app.Exponencial1Tab);
            app.UITable2.ColumnName = {'Días'; 'Proyección'};
            app.UITable2.RowName = {};
            app.Exponencial2Tab = uitab(app.TabGroup3_2);
            app.Exponencial2Tab.Title = 'Exponencial2';
            app.axesModeloExponencial1 = uiaxes(app.Exponencial2Tab);
            title(app.axesModeloExponencial1, 'Modelo Exponencial1')
            xlabel(app.axesModeloExponencial1, 'Días')
            ylabel(app.axesModeloExponencial1, 'Número de confirmados')
            app.fxaxbLabel = uilabel(app.Exponencial2Tab);
            app.fxaxbLabel.Text = ' f(x) = a*x^b';
            app.RMSEEditFieldexponen1 = uilabel(app.Exponencial2Tab);
            app.RMSEEditFieldexponen1.Text = 'RMSE';
            app.RMSEEditField_2 = uieditfield(app.Exponencial2Tab, 'numeric');
            app.ProyeccindecasosLabel_3 = uilabel(app.Exponencial2Tab);
            app.ProyeccindecasosLabel_3.Text = 'Proyección de casos';
            app.UITableexponen1 = uitable(app.Exponencial2Tab);
            app.UITableexponen1.ColumnName = {'Días'; 'Proyección'};
            app.UITableexponen1.RowName = {};
            app.aEditFieldLabel_2 = uilabel(app.Exponencial2Tab);
            app.aEditFieldLabel_2.Text = 'a';
            app.valorde_a1 = uieditfield(app.Exponencial2Tab, 'numeric');
            app.bEditFieldLabel_2 = uilabel(app.Exponencial2Tab);
            app.bEditFieldLabel_2.Text = 'b';
            app.valorde_b1 = uieditfield(app.Exponencial2Tab, 'numeric');
            app.R2EditField_3Label = uilabel(app.Exponencial2Tab);
            app.R2EditField_3Label.Text = 'R^2';
            app.R2EditFieldexponen1 = uieditfield(app.Exponencial2Tab, 'numeric');
            app.CuadrticoTab = uitab(app.TabGroup3_2);
            app.CuadrticoTab.Title = 'Cuadrático';
            app.axesModeloCuadratico = uiaxes(app.CuadrticoTab);
            title(app.axesModeloCuadratico, 'Modelo Cuadrático')
            xlabel(app.axesModeloCuadratico, 'Días')
            ylabel(app.axesModeloCuadratico, 'Número de confirmados')
            app.fxax2bxcLabel = uilabel(app.CuadrticoTab);
            app.fxax2bxcLabel.Text = ' f(x) = a*x^2 + b*x + c';
            app.aLabel = uilabel(app.CuadrticoTab);
            app.aLabel.Text = 'a';
            app.valorde_a_2 = uieditfield(app.CuadrticoTab, 'numeric');
            app.bLabel = uilabel(app.CuadrticoTab);
            app.bLabel.Text = 'b';
            app.valorde_b_2 = uieditfield(app.CuadrticoTab, 'numeric');
            app.cLabel = uilabel(app.CuadrticoTab);
            app.cLabel.Text = 'c';
            app.valorde_c_2 = uieditfield(app.CuadrticoTab, 'numeric');
            app.ProyeccindecasosLabel_2 = uilabel(app.CuadrticoTab);
            app.ProyeccindecasosLabel_2.Text = 'Proyección de casos';
            app.UITable2_2 = uitable(app.CuadrticoTab);
            app.UITable2_2.ColumnName = {'Días'; 'Proyección'};
            app.UITable2_2.RowName = {};
            app.RMSEEditFieldLabel_2 = uilabel(app.CuadrticoTab);
            app.RMSEEditFieldLabel_2.Text = 'RMSE';
            app.RMSEEditField_3 = uieditfield(app.CuadrticoTab, 'numeric');
            app.R2EditField_3Label_2 = uilabel(app.CuadrticoTab);
            app.R2EditField_3Label_2.Text = 'R^2';
            app.R2EditField_3 = uieditfield(app.CuadrticoTab, 'numeric');
            app.LinealTab = uitab(app.TabGroup3_2);
            app.LinealTab.Title = 'Lineal';
            app.axesModeloExponencial_3 = uiaxes(app.LinealTab);
            title(app.axesModeloExponencial_3, 'Modelo Lineal')
            xlabel(app.axesModeloExponencial_3, 'Días')
            ylabel(app.axesModeloExponencial_3, 'Número de confirmados')
            app.fxaxbLabel_2 = uilabel(app.LinealTab);
            app.fxaxbLabel_2.Text = ' f(x) = a*x + b';
            app.ProyeccindecasosLabel_4 = uilabel(app.LinealTab);
            app.ProyeccindecasosLabel_4.Text = 'Proyección de casos';
            app.UITableexponen1_2 = uitable(app.LinealTab);
            app.UITableexponen1_2.ColumnName = {'Días'; 'Proyección'};
            app.UITableexponen1_2.RowName = {};
            app.RMSEEditFieldLabel_3 = uilabel(app.LinealTab);
            app.RMSEEditFieldLabel_3.Text = 'RMSE';
            app.RMSEEditField_4 = uieditfield(app.LinealTab, 'numeric');
            app.aEditFieldLabel_3 = uilabel(app.LinealTab);
            app.aEditFieldLabel_3.Text = 'a';
            app.valorde_a_3 = uieditfield(app.LinealTab, 'numeric');
            app.bEditFieldLabel_3 = uilabel(app.LinealTab);
            app.bEditFieldLabel_3.Text = 'b';
            app.valorde_b_3 = uieditfield(app.LinealTab, 'numeric');
            app.R2EditField_4Label = uilabel(app.LinealTab);
            app.R2EditField_4Label.Text = 'R^2';
            app.R2EditField_4 = uieditfield(app.LinealTab, 'numeric');
            app.ParaobservarqueelmodeloCOVID19noeslinealLabel = uilabel(app.LinealTab);
            app.ParaobservarqueelmodeloCOVID19noeslinealLabel.Text = '* Para observar que el modelo (COVID-19) no es lineal';
            app.SegmentacinLabel = uilabel(app.NacionalTab);
            app.SegmentacinLabel.Text = 'Segmentación';
            app.TotalCasosConfirmadosEditFieldLabel = uilabel(app.NacionalTab);
            app.TotalCasosConfirmadosEditFieldLabel.Text = 'Total Casos Confirmados ';
            app.TotalCasosConfirmadosEditField = uieditfield(app.NacionalTab, 'numeric');
            app.TotalCasosConfirmadosEditField.ValueChangedFcn = createCallbackFcn(app, @TotalCasosConfirmadosEditFieldValueChanged, true);
            app.Label_2 = uilabel(app.NacionalTab);
            app.Label_2.Text = '*';
            app.CargandoPlot = uieditfield(app.NacionalTab, 'text');
            app.CargandoPlot.ValueChangedFcn = createCallbackFcn(app, @CargandoPlotValueChanged, true);
            app.CargandoPlot.ValueChangingFcn = createCallbackFcn(app, @CargandoPlotValueChanging, true);
            app.CargandoPlot.Value = 'Da click y espera unos segundos hasta que se generen todos los datos';
            app.PrimerCasoConfirmadoEditFieldLabel = uilabel(app.NacionalTab);
            app.PrimerCasoConfirmadoEditFieldLabel.Text = 'Primer Caso Confirmado';
            app.PrimerCasoConfirmadoEditField = uieditfield(app.NacionalTab, 'text');
            app.PrimerCasoConfirmadoEditField.ValueChangedFcn = createCallbackFcn(app, @PrimerCasoConfirmadoEditFieldValueChanged, true);
            app.PrimerCasoConfirmadoEditField.Value = '0';
            app.TasadeletalidadEditField_2Label = uilabel(app.NacionalTab);
            app.TasadeletalidadEditField_2Label.Text = 'Tasa de letalidad';
            app.TasadeletalidadTotalidadEditField = uieditfield(app.NacionalTab, 'text');
            app.TasadeletalidadTotalidadEditField.Value = '0';
            app.DepartamentalTab = uitab(app.TabGroup);
            app.DepartamentalTab.Title = 'Departamental';
            app.axesDepartamentos = uiaxes(app.DepartamentalTab);
            title(app.axesDepartamentos, {''; ''})
            xlabel(app.axesDepartamentos, {''; ''})
            ylabel(app.axesDepartamentos, {''; ''})
            app.ConfirmadosEditFieldLabel = uilabel(app.DepartamentalTab);
            app.ConfirmadosEditFieldLabel.Text = 'Confirmados';
            app.ConfirmadosDeparEditField = uieditfield(app.DepartamentalTab, 'numeric');
            app.ConfirmadosDeparEditField.ValueChangedFcn = createCallbackFcn(app, @ConfirmadosDeparEditFieldValueChanged, true);
            app.RecuperadosEditField_2Label = uilabel(app.DepartamentalTab);
            app.RecuperadosEditField_2Label.Text = 'Recuperados';
            app.RecuperadosDeparEditField = uieditfield(app.DepartamentalTab, 'numeric');
            app.RecuperadosDeparEditField.ValueChangedFcn = createCallbackFcn(app, @RecuperadosDeparEditFieldValueChanged, true);
            app.FallecidosEditField_2Label = uilabel(app.DepartamentalTab);
            app.FallecidosEditField_2Label.Text = 'Fallecidos';
            app.FallecidosDeparEditField = uieditfield(app.DepartamentalTab, 'numeric');
            app.FallecidosDeparEditField.ValueChangedFcn = createCallbackFcn(app, @FallecidosDeparEditFieldValueChanged, true);
            app.ListaDepartamentos = uilistbox(app.DepartamentalTab);
            app.ListaDepartamentos.Items = {'Amazonas', 'Antioquia', 'Atlántico', 'Barranquilla D.E.', 'Bogotá D.C.', 'Bolívar', 'Boyacá', 'Buenaventura D.E.', 'Caldas', 'Caquetá', 'Cartagena D.T. y C.', 'Casanare', 'Cauca', 'Cesar', 'Chocó', 'Cundinamarca', 'Córdoba', 'Huila', 'La Guajira', 'Magdalena', 'Meta', 'Nariño', 'Norte de Santander', 'Quindio', 'Risaralda', 'San Andrés', 'Santa Marta D.T. y C.', 'Santander', 'Sucre', 'Tolima', 'Valle del Cauca'};
            app.ListaDepartamentos.ValueChangedFcn = createCallbackFcn(app, @ListaDepartamentosValueChanged, true);
            app.ListaDepartamentos.Value = 'Amazonas';
            app.DepartamentosEditFieldLabel = uilabel(app.DepartamentalTab);
            app.DepartamentosEditFieldLabel.Text = 'Departamentos';
            app.DepartamentosEditField = uieditfield(app.DepartamentalTab, 'numeric');
            app.DepartamentosEditField.ValueChangedFcn = createCallbackFcn(app, @DepartamentosEditFieldValueChanged, true);
            app.axesComparDepartamento = uiaxes(app.DepartamentalTab);
            title(app.axesComparDepartamento, '')
            xlabel(app.axesComparDepartamento, '')
            ylabel(app.axesComparDepartamento, {''; ''})
            app.axesComparDepartamento.Visible = 'off';
            app.Label = uilabel(app.DepartamentalTab);
            app.Label.Text = '*';
            app.DeparEditField = uieditfield(app.DepartamentalTab, 'text');
            app.DeparEditField.ValueChangedFcn = createCallbackFcn(app, @DeparEditFieldValueChanged, true);
            app.DeparEditField.Editable = 'off';
            app.TasadeletalidadEditFieldLabel = uilabel(app.DepartamentalTab);
            app.TasadeletalidadEditFieldLabel.Text = 'Tasa de letalidad';
            app.TasadeletalidadDeparEditField = uieditfield(app.DepartamentalTab, 'text');
            app.TasadeletalidadDeparEditField.ValueChangedFcn = createCallbackFcn(app, @TasadeletalidadDeparEditFieldValueChanged, true);
            app.TasadeletalidadDeparEditField.Value = '0';
            app.ActivosEditField_2Label = uilabel(app.DepartamentalTab);
            app.ActivosEditField_2Label.Text = 'Activos';
            app.ActivosDeparEditField = uieditfield(app.DepartamentalTab, 'numeric');
            app.ActivosDeparEditField.ValueChangedFcn = createCallbackFcn(app, @ActivosDeparEditFieldValueChanged, true);
            app.TabGroup4 = uitabgroup(app.DepartamentalTab);
            app.AtencinyEstadoTab_2 = uitab(app.TabGroup4);
            app.AtencinyEstadoTab_2.Title = 'Atención y Estado';
            app.Label_6 = uilabel(app.AtencinyEstadoTab_2);
            app.Label_6.Text = '*';
            app.EnCasaDeparEditField = uieditfield(app.AtencinyEstadoTab_2, 'numeric');
            app.EnCasaDeparEditField.ValueChangedFcn = createCallbackFcn(app, @EnCasaDeparEditFieldValueChanged, true);
            app.Label_7 = uilabel(app.AtencinyEstadoTab_2);
            app.Label_7.Text = '*';
            app.HospitalDeparEditField = uieditfield(app.AtencinyEstadoTab_2, 'numeric');
            app.HospitalDeparEditField.ValueChangedFcn = createCallbackFcn(app, @HospitalDeparEditFieldValueChanged, true);
            app.Label_8 = uilabel(app.AtencinyEstadoTab_2);
            app.Label_8.Text = '*';
            app.HospitalUCIDeparEditField = uieditfield(app.AtencinyEstadoTab_2, 'numeric');
            app.HospitalUCIDeparEditField.ValueChangedFcn = createCallbackFcn(app, @HospitalUCIDeparEditFieldValueChanged, true);
            app.axesEstadoPacienteDepar = uiaxes(app.AtencinyEstadoTab_2);
            title(app.axesEstadoPacienteDepar, {'Estado de casos Departamento'; ''})
            xlabel(app.axesEstadoPacienteDepar, '')
            ylabel(app.axesEstadoPacienteDepar, '')
            app.Label_3 = uilabel(app.AtencinyEstadoTab_2);
            app.Label_3.Text = '*';
            app.LeveDeparEditField = uieditfield(app.AtencinyEstadoTab_2, 'numeric');
            app.LeveDeparEditField.ValueChangedFcn = createCallbackFcn(app, @LeveDeparEditFieldValueChanged, true);
            app.Label_4 = uilabel(app.AtencinyEstadoTab_2);
            app.Label_4.Text = '*';
            app.ModeradoDeparEditField = uieditfield(app.AtencinyEstadoTab_2, 'numeric');
            app.ModeradoDeparEditField.ValueChangedFcn = createCallbackFcn(app, @ModeradoDeparEditFieldValueChanged, true);
            app.Label_5 = uilabel(app.AtencinyEstadoTab_2);
            app.Label_5.Text = '*';
            app.GraveDeparEditField = uieditfield(app.AtencinyEstadoTab_2, 'numeric');
            app.GraveDeparEditField.ValueChangedFcn = createCallbackFcn(app, @GraveDeparEditFieldValueChanged, true);
            app.axesCasosDepa = uiaxes(app.AtencinyEstadoTab_2);
            title(app.axesCasosDepa, {'Distribución de casos Departamento'; ''})
            xlabel(app.axesCasosDepa, '')
            ylabel(app.axesCasosDepa, '')
            app.axesCasosDepa.Visible = 'off';
            app.axesAtencionPacienteDepar = uiaxes(app.AtencinyEstadoTab_2);
            title(app.axesAtencionPacienteDepar, {'Atención de casos Departamento'; ''})
            xlabel(app.axesAtencionPacienteDepar, '')
            ylabel(app.axesAtencionPacienteDepar, '')
            app.EdadesyGneroTab_2 = uitab(app.TabGroup4);
            app.EdadesyGneroTab_2.Title = 'Edades y Género';
            app.axesEdadesDepartamentos = uiaxes(app.EdadesyGneroTab_2);
            title(app.axesEdadesDepartamentos, {'Distribución de edad Departamento'; ''})
            xlabel(app.axesEdadesDepartamentos, '')
            ylabel(app.axesEdadesDepartamentos, '')
            app.axesPieDepartamentos = uiaxes(app.EdadesyGneroTab_2);
            title(app.axesPieDepartamentos, {'Distribución de género Departamento'; ''})
            xlabel(app.axesPieDepartamentos, '')
            ylabel(app.axesPieDepartamentos, '')
            app.axesPieDepartamentos.Visible = 'off';
            app.MujeresEditField_2Label = uilabel(app.EdadesyGneroTab_2);
            app.MujeresEditField_2Label.Text = 'Mujeres';
            app.MujeresDeparEditField = uieditfield(app.EdadesyGneroTab_2, 'numeric');
            app.HombresEditField_2Label = uilabel(app.EdadesyGneroTab_2);
            app.HombresEditField_2Label.Text = 'Hombres';
            app.HombresDeparEditField = uieditfield(app.EdadesyGneroTab_2, 'numeric');
            app.GenerarlacurvaButton = uibutton(app.DepartamentalTab, 'push');
            app.GenerarlacurvaButton.ButtonPushedFcn = createCallbackFcn(app, @GenerarlacurvaButtonPushed, true);
            app.GenerarlacurvaButton.Text = 'Generar la curva';
            app.ElprocesopuedetardarunpocoLabel = uilabel(app.DepartamentalTab);
            app.ElprocesopuedetardarunpocoLabel.Text = '* El proceso puede tardar un poco';
            app.CiudadMunicipioTab = uitab(app.TabGroup);
            app.CiudadMunicipioTab.Title = 'Ciudad/Municipio';
            app.ListaCiudades = uilistbox(app.CiudadMunicipioTab);
            app.ListaCiudades.Items = {'Armenia', 'Bello', 'Bogotá D.C.', 'Bucaramanga', 'Cali', 'Cartagena de Indias', 'Cúcuta', 'Envigado', 'Espinal', 'Florencia', 'Ibagué', 'Leticia', 'Manizales', 'Medellín', 'Montería', 'Neiva', 'Palmira', 'Pasto', 'Pereira', 'Popayán', 'Riohacha', 'Rionegro', 'Sincelejo', 'Soacha', 'Soledad', 'Tunja', 'Valledupar', 'Villavicencio', 'Yopal', 'Yumbo', 'Zipaquirá'};
            app.ListaCiudades.ValueChangedFcn = createCallbackFcn(app, @ListaCiudadesValueChanged, true);
            app.ListaCiudades.Value = 'Armenia';
            app.MunicipiosEditFieldLabel = uilabel(app.CiudadMunicipioTab);
            app.MunicipiosEditFieldLabel.Text = 'Municipios';
            app.MunicipiosEditField = uieditfield(app.CiudadMunicipioTab, 'numeric');
            app.MunicipiosEditField.ValueChangedFcn = createCallbackFcn(app, @MunicipiosEditFieldValueChanged, true);
            app.UITable = uitable(app.CiudadMunicipioTab);
            app.UITable.ColumnName = {'Número'; 'Municipios'; 'Confirmados'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = true;
            app.UITable.ColumnEditable = true;
            app.UITable.DisplayDataChangedFcn = createCallbackFcn(app, @UITableDisplayDataChanged2, true);
            app.PanelMapaDepa = uipanel(app.CiudadMunicipioTab);
            app.PanelArreglo = uipanel(app.CiudadMunicipioTab);
            app.PanelArreglo.Title = 'Panel';
            app.PanelArreglo.Visible = 'off';
            app.MapadeContagiosLabel = uilabel(app.CiudadMunicipioTab);
            app.MapadeContagiosLabel.Text = 'Mapa de Contagios';
            app.ConfirmadosEditFieldLabel_2 = uilabel(app.CiudadMunicipioTab);
            app.ConfirmadosEditFieldLabel_2.Text = 'Confirmados';
            app.ConfirmadosDeparEditField_2 = uieditfield(app.CiudadMunicipioTab, 'numeric');
            app.ConfirmadosDeparEditField_2.ValueChangedFcn = createCallbackFcn(app, @ConfirmadosDeparEditField_2ValueChanged, true);
            app.RecuperadosEditField_2Label_2 = uilabel(app.CiudadMunicipioTab);
            app.RecuperadosEditField_2Label_2.Text = 'Recuperados';
            app.RecuperadosDeparEditField_2 = uieditfield(app.CiudadMunicipioTab, 'numeric');
            app.RecuperadosDeparEditField_2.ValueChangedFcn = createCallbackFcn(app, @RecuperadosDeparEditField_2ValueChanged, true);
            app.FallecidosEditField_2Label_2 = uilabel(app.CiudadMunicipioTab);
            app.FallecidosEditField_2Label_2.Text = 'Fallecidos';
            app.FallecidosDeparEditField_2 = uieditfield(app.CiudadMunicipioTab, 'numeric');
            app.FallecidosDeparEditField_2.ValueChangedFcn = createCallbackFcn(app, @FallecidosDeparEditField_2ValueChanged, true);
            app.TabGroup5 = uitabgroup(app.CiudadMunicipioTab);
            app.AtencinTab = uitab(app.TabGroup5);
            app.AtencinTab.Title = 'Atención';
            app.axesAtencionPacienteDepar_2 = uiaxes(app.AtencinTab);
            title(app.axesAtencionPacienteDepar_2, {'Atención de casos Ciudad'; ''})
            xlabel(app.axesAtencionPacienteDepar_2, '')
            ylabel(app.axesAtencionPacienteDepar_2, '')
            app.EnCasaEditField_2Label = uilabel(app.AtencinTab);
            app.EnCasaEditField_2Label.Text = 'En Casa';
            app.EnCasaEditField_2 = uieditfield(app.AtencinTab, 'numeric');
            app.EnCasaEditField_2.ValueChangedFcn = createCallbackFcn(app, @EnCasaEditField_2ValueChanged, true);
            app.HospitalEditField_2Label = uilabel(app.AtencinTab);
            app.HospitalEditField_2Label.Text = 'Hospital';
            app.HospitalEditField_2 = uieditfield(app.AtencinTab, 'numeric');
            app.HospitalEditField_2.ValueChangedFcn = createCallbackFcn(app, @HospitalEditField_2ValueChanged, true);
            app.HospitalUCIEditField_2Label = uilabel(app.AtencinTab);
            app.HospitalUCIEditField_2Label.Text = 'Hospital UCI';
            app.HospitalUCIEditField_2 = uieditfield(app.AtencinTab, 'numeric');
            app.HospitalUCIEditField_2.ValueChangedFcn = createCallbackFcn(app, @HospitalUCIEditField_2ValueChanged, true);
            app.EstadoTab = uitab(app.TabGroup5);
            app.EstadoTab.Title = 'Estado';
            app.axesEstadoPacienteDepar_2 = uiaxes(app.EstadoTab);
            title(app.axesEstadoPacienteDepar_2, {'Estado de casos Ciudad'; ''})
            xlabel(app.axesEstadoPacienteDepar_2, '')
            ylabel(app.axesEstadoPacienteDepar_2, '')
            app.LeveEditField_2Label = uilabel(app.EstadoTab);
            app.LeveEditField_2Label.Text = 'Leve';
            app.LeveEditField_2 = uieditfield(app.EstadoTab, 'numeric');
            app.LeveEditField_2.ValueChangedFcn = createCallbackFcn(app, @LeveEditField_2ValueChanged, true);
            app.ModeradoEditField_2Label = uilabel(app.EstadoTab);
            app.ModeradoEditField_2Label.Text = 'Moderado';
            app.ModeradoEditField_2 = uieditfield(app.EstadoTab, 'numeric');
            app.ModeradoEditField_2.ValueChangedFcn = createCallbackFcn(app, @ModeradoEditField_2ValueChanged, true);
            app.GraveEditField_2Label = uilabel(app.EstadoTab);
            app.GraveEditField_2Label.Text = 'Grave';
            app.GraveEditField_2 = uieditfield(app.EstadoTab, 'numeric');
            app.GraveEditField_2.ValueChangedFcn = createCallbackFcn(app, @GraveEditField_2ValueChanged, true);
            app.EdadTab = uitab(app.TabGroup5);
            app.EdadTab.Title = 'Edad';
            app.axesEstadoPacienteDepar_3 = uiaxes(app.EdadTab);
            title(app.axesEstadoPacienteDepar_3, {'Distribución de edad Ciudad'; ''})
            xlabel(app.axesEstadoPacienteDepar_3, '')
            ylabel(app.axesEstadoPacienteDepar_3, '')
            app.Menoresa35aosEditField_2Label = uilabel(app.EdadTab);
            app.Menoresa35aosEditField_2Label.Text = 'Menores a 35 años';
            app.Menoresa35aosEditField_2 = uieditfield(app.EdadTab, 'numeric');
            app.Menoresa35aosEditField_2.ValueChangedFcn = createCallbackFcn(app, @Menoresa35aosEditField_2ValueChanged, true);
            app.Mayoresa35aosEditField_2Label = uilabel(app.EdadTab);
            app.Mayoresa35aosEditField_2Label.Text = 'Mayores a 35 años';
            app.Mayoresa35aosEditField_2 = uieditfield(app.EdadTab, 'numeric');
            app.Mayoresa35aosEditField_2.ValueChangedFcn = createCallbackFcn(app, @Mayoresa35aosEditField_2ValueChanged, true);
            app.GneroTab = uitab(app.TabGroup5);
            app.GneroTab.Title = 'Género';
            app.axesEstadoPacienteDepar_4 = uiaxes(app.GneroTab);
            title(app.axesEstadoPacienteDepar_4, 'Distribución de género Ciudad')
            xlabel(app.axesEstadoPacienteDepar_4, '')
            ylabel(app.axesEstadoPacienteDepar_4, '')
            app.HombresEditField_2Label_2 = uilabel(app.GneroTab);
            app.HombresEditField_2Label_2.Text = 'Hombres';
            app.HombresDeparEditField_2 = uieditfield(app.GneroTab, 'numeric');
            app.HombresDeparEditField_2.ValueChangedFcn = createCallbackFcn(app, @HombresDeparEditField_2ValueChanged, true);
            app.MujeresEditField_2Label_2 = uilabel(app.GneroTab);
            app.MujeresEditField_2Label_2.Text = 'Mujeres';
            app.MujeresDeparEditField_2 = uieditfield(app.GneroTab, 'numeric');
            app.MujeresDeparEditField_2.ValueChangedFcn = createCallbackFcn(app, @MujeresDeparEditField_2ValueChanged, true);
            app.ComparacinTab = uitab(app.TabGroup5);
            app.ComparacinTab.Title = 'Comparación';
            app.axesEstadoPacienteDepar_5 = uiaxes(app.ComparacinTab);
            title(app.axesEstadoPacienteDepar_5, '')
            xlabel(app.axesEstadoPacienteDepar_5, '')
            ylabel(app.axesEstadoPacienteDepar_5, '')
            app.axesEstadoPacienteDepar_5.Visible = 'off';
            app.Label_9 = uilabel(app.ComparacinTab);
            app.Label_9.Text = '*';
            app.DeparEditField_2 = uieditfield(app.ComparacinTab, 'text');
            app.DeparEditField_2.Editable = 'off';
            app.TasadeletalidadEditFieldLabel_2 = uilabel(app.ComparacinTab);
            app.TasadeletalidadEditFieldLabel_2.Text = 'Tasa de letalidad';
            app.TasadeletalidadDeparEditField_2 = uieditfield(app.ComparacinTab, 'text');
            app.TasadeletalidadDeparEditField_2.Value = '0';
            app.SobreNosotrosTab = uitab(app.TabGroup);
            app.SobreNosotrosTab.Title = 'Sobre Nosotros';
            app.CristianFRubioAguiarLabel = uilabel(app.SobreNosotrosTab);
            app.CristianFRubioAguiarLabel.Text = 'Cristian F. Rubio Aguiar.';
            app.IngJoseAFernndezGallegoLabel = uilabel(app.SobreNosotrosTab);
            app.IngJoseAFernndezGallegoLabel.Text = 'Ing. Jose A Fernández Gallego';
            app.UniversidaddeIbaguLabel = uilabel(app.SobreNosotrosTab);
            app.UniversidaddeIbaguLabel.Text = 'Universidad de Ibagué';
            app.FacultaddeIngenieraElectrnicaLabel = uilabel(app.SobreNosotrosTab);
            app.FacultaddeIngenieraElectrnicaLabel.Text = 'Facultad de Ingeniería Electrónica';
            app.EstudiantedeingenieraelectrnicadesarrolladorLabel = uilabel(app.SobreNosotrosTab);
            app.EstudiantedeingenieraelectrnicadesarrolladorLabel.Text = 'Estudiante de ingeniería electrónica desarrollador';
            app.DirectorycoordinadorasociadoaldesarrollodelproyectoLabel = uilabel(app.SobreNosotrosTab);
            app.DirectorycoordinadorasociadoaldesarrollodelproyectoLabel.Text = 'Director y coordinador asociado al desarrollo del proyecto ';
            app.Panel = uipanel(app.SobreNosotrosTab);
            app.Image4 = uiimage(app.Panel);
            app.Image4.ImageSource = 'cristian.png';
            app.Panel_2 = uipanel(app.SobreNosotrosTab);
            app.Image3 = uiimage(app.Panel_2);
            app.Image3.ImageSource = 'jose.jpg';
            app.httpsgithubcomcristianrubioaButton = uibutton(app.SobreNosotrosTab, 'push');
            app.httpsgithubcomcristianrubioaButton.ButtonPushedFcn = createCallbackFcn(app, @httpsgithubcomcristianrubioaButtonPushed, true);
            app.httpsgithubcomcristianrubioaButton.Text = 'https://github.com/cristianrubioa';
            app.GitHubLabel = uilabel(app.SobreNosotrosTab);
            app.GitHubLabel.Text = 'GitHub';
            app.LinkedinLabel = uilabel(app.SobreNosotrosTab);
            app.LinkedinLabel.Text = 'Linkedin';
            app.httpswwwlinkedincomincristianrubioaguiarButton = uibutton(app.SobreNosotrosTab, 'push');
            app.httpswwwlinkedincomincristianrubioaguiarButton.ButtonPushedFcn = createCallbackFcn(app, @httpswwwlinkedincomincristianrubioaguiarButtonPushed, true);
            app.httpswwwlinkedincomincristianrubioaguiarButton.Text = 'https://www.linkedin.com/in/cristianrubioaguiar';
            app.ResearchgateLabel = uilabel(app.SobreNosotrosTab);
            app.ResearchgateLabel.Text = 'Researchgate';
            app.httpswwwresearchgatenetprofileJose_FernandezGallegoButton = uibutton(app.SobreNosotrosTab, 'push');
            app.httpswwwresearchgatenetprofileJose_FernandezGallegoButton.ButtonPushedFcn = createCallbackFcn(app, @httpswwwresearchgatenetprofileJose_FernandezGallegoButtonPushed, true);
            app.httpswwwresearchgatenetprofileJose_FernandezGallegoButton.Text = 'https://www.researchgate.net/profile/Jose_Fernandez-Gallego';
            app.EMailLabel = uilabel(app.SobreNosotrosTab);
            app.EMailLabel.Text = 'E-Mail';
            app.httpcristianrubioagmailcomButton = uibutton(app.SobreNosotrosTab, 'push');
            app.httpcristianrubioagmailcomButton.ButtonPushedFcn = createCallbackFcn(app, @httpcristianrubioagmailcomButtonPushed, true);
            app.httpcristianrubioagmailcomButton.Text = 'http://cristianrubioa@gmail.com';
            app.EMailLabel_2 = uilabel(app.SobreNosotrosTab);
            app.EMailLabel_2.Text = 'E-Mail';
            app.httpjosefernandezunibagueeducoButton = uibutton(app.SobreNosotrosTab, 'push');
            app.httpjosefernandezunibagueeducoButton.ButtonPushedFcn = createCallbackFcn(app, @httpjosefernandezunibagueeducoButtonPushed, true);
            app.httpjosefernandezunibagueeducoButton.Text = 'http://jose.fernandez@unibague.edu.co';
            app.SistemadesarrolladoenunespaciodelaasignaturadeIAreferenteLabel = uilabel(app.SobreNosotrosTab);
            app.SistemadesarrolladoenunespaciodelaasignaturadeIAreferenteLabel.Text = 'Sistema desarrollado en el espacio de investigación de la asignatura de Inteligencia Artificial (AI) como respuesta  ';
            app.SistemadesarrolladoenunespaciodelaasignaturadeIAreferenteLabel_2 = uilabel(app.SobreNosotrosTab);
            app.SistemadesarrolladoenunespaciodelaasignaturadeIAreferenteLabel_2.Text = ' al panoráma actual y al challenge "Reto Gráficas MATLAB 2020". ';
            app.Image5 = uiimage(app.SobreNosotrosTab);
            app.Image5.ImageSource = 'u.png';
            app.FechaReporteEditFieldLabel = uilabel(app.UIFigure);
            app.FechaReporteEditFieldLabel.Text = 'Fecha Reporte:';
            app.FechaReporteEditField = uieditfield(app.UIFigure, 'text');
            app.FechaReporteEditField.ValueChangedFcn = createCallbackFcn(app, @FechaReporteEditFieldValueChanged, true);
            app.FechaReporteEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = COVID19_Colombia_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end